classdef alyxGUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		alyxGUIUIFigure             matlab.ui.Figure
		GridLayout                  matlab.ui.container.GridLayout
		LeftPanel                   matlab.ui.container.Panel
		GridLayout2                 matlab.ui.container.GridLayout
		ChangeButton                matlab.ui.control.Button
		PasswordReset               matlab.ui.control.DropDown
		DefaultValuesLabel          matlab.ui.control.Label
		text4554_9                  matlab.ui.control.Label
		text4554_8                  matlab.ui.control.Label
		OKDataBucket                matlab.ui.control.EditField
		OKDataRepo                  matlab.ui.control.EditField
		OKAlyxUpload                matlab.ui.control.Button
		OKAlyxSelectFolders         matlab.ui.control.Button
		FolderListListBox           matlab.ui.control.ListBox
		FolderListListBoxLabel      matlab.ui.control.Label
		OKAlyxLOGINButton           matlab.ui.control.Button
		OKAlyxLOGOUTButton          matlab.ui.control.Button
		OKAlyxTaskProtocol          matlab.ui.control.EditField
		TaskProtocolEditFieldLabel  matlab.ui.control.Label
		text4554_7                  matlab.ui.control.Label
		OKAlyxBrainRegion           matlab.ui.control.DropDown
		text4554_6                  matlab.ui.control.Label
		text4554_5                  matlab.ui.control.Label
		OKAlyxProject               matlab.ui.control.DropDown
		OKAlyxProcedure             matlab.ui.control.DropDown
		OKAlyxIP                    matlab.ui.control.EditField
		OKAlyxUser                  matlab.ui.control.EditField
		text4554_4                  matlab.ui.control.Label
		text4554_3                  matlab.ui.control.Label
		text4554_2                  matlab.ui.control.Label
		OKLabLocation               matlab.ui.control.EditField
		text209_2                   matlab.ui.control.Label
		OKLabName                   matlab.ui.control.EditField
		text4554                    matlab.ui.control.Label
		OKResearcher                matlab.ui.control.EditField
		text209                     matlab.ui.control.Label
		OKSubject                   matlab.ui.control.EditField
		CEBSITAlyxDatabaseInterfaceV10Label  matlab.ui.control.Label
		Image                       matlab.ui.control.Image
		RightPanel                  matlab.ui.container.Panel
		GridLayout3                 matlab.ui.container.GridLayout
		OKAlyxRequest               CompactEditableComboBox
		OKAlyxRequestButton         matlab.ui.control.Button
		text209_3                   matlab.ui.control.Label
		UITable                     matlab.ui.control.Table
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	
	properties (Access = public)
		alyx % Description
	end
	
	properties (Access = private)
		uiPrefsList cell = {'IPAddress', 'RemoteServerPortEditField',...
			'ScreenNumberEditField',...
			'alyxIP', 'alyxUser',...
			'OKAlyxRequest',...
			'OKResearcher', 'OKLabName', 'OKSubject', '4OKLabLocation',...
			'OKDataBucket','OKDataRepo',...
			'folderEditField',...
			}
	end
	
	methods (Access = private)
		
		function loadPrefs(app)
			if ~ispref('alyxgui'); return; end
			anyLoaded = false; prefnames = ""; a = 1;
			fn = fieldnames(app);
			for i = 1:length(fn)
				prfname = fn{i};
				if ispref('alyxgui',prfname) %pref exists
					if isprop(app, prfname) %ui widget exists
						myhandle = app.(prfname);
						prf = getpref('alyxgui', prfname);
						uiType = myhandle.Type;
						thisVal = '';
						switch uiType
							case 'uieditfield'
								if ischar(prf)
									myhandle.Value = prf;
									thisVal = prf;
								else
									myhandle.Value = num2str(prf);
									thisVal = myhandle.Value;
								end
							case 'uinumericeditfield'
								myhandle.Value = prf;
								thisVal = num2str(prf);
							case 'uicheckbox'
								if islogical(prf) || isnumeric(prf)
									myhandle.Value = prf;
									thisVal = num2str(prf);
								end
							case {'uidropdown','compacteditablecombobox'}
								str = myhandle.Items;
								if ischar(prf) && any(contains(prf, str))
									myhandle.Value = prf;
									thisVal = prf;
								elseif iscell(prf) & length(prf)==2
									myhandle.Items = prf{1};
									myhandle.Value = prf{2};
									thisVal = prf{2};
								end
							case 'uimenu'
								myhandle.Checked = prf;
								thisVal = char(prf);
							case 'uirockerswitch'
								if strcmpi(prf,'on') || strcmpi(prf,'off')
									myhandle.Value = prf;
									thisVal = prf;
								end
						end
						prefnames = [prefnames " " prfname " «" thisVal "»"];
						if ~mod(a,3); prefnames = [prefnames "\n"]; end
						if ~anyLoaded; anyLoaded = true; end
						a = a + 1;
					end
				end
			end
			if anyLoaded
				fprintf('\n≣≣≣≣⊱  alyxGUI Load Preferences:\n'); fprintf(join(prefnames)); fprintf('\n');
			end
		end

		function savePrefs(app)
			if ispref('alyxgui'); rmpref('alyxgui'); end
			anySaved = false; prefnames = ""; a = 1;
			for i = 1:length(app.uiPrefsList)
				prf = [];
				prfname = app.uiPrefsList{i};
				if ~isprop(app,prfname); continue; end
				try
					myhandle = app.(prfname);
					uiType = myhandle.Type;
					switch uiType
						case {'uinumericeditfield','uieditfield','uirockerswitch'}
							prf = myhandle.Value;
						case {'uidropdown','compacteditablecombobox'}
							prf = {myhandle.Items, myhandle.Value};
						case 'uicheckbox'
							prf = myhandle.Value;
							if ~islogical(prf); prf=logical(prf);end
						case 'uimenu'
							prf = myhandle.Checked;
					end
					if ~isempty(prf)
						setpref('alyxgui', prfname, prf);
						prefnames = [prefnames " " prfname " «"  strip(formattedDisplayText(prf)) "»"];
						if ~mod(a,3); prefnames = [prefnames "\n"]; end
						a = a + 1;
					end
					if ~anySaved; anySaved = true; end
				end
			end
			if anySaved; fprintf('\n≣≣≣≣⊱ CageLab Save Preferences:\n'); fprintf(join(prefnames)); fprintf('\n');end
		end
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			app.alyx = alyxManager; % ALYX connection

			try
				app.loadPrefs(); % load saved prefs
			end

			if isempty(app.OKAlyxRequest.Items)
				app.OKAlyxRequest.Items = {'sessions?subject=TestSubject', 'weighings?subject=TestSubject'};
				app.OKAlyxRequest.Value = 'sessions?subject=TestSubject';
			end

		end

		% Button pushed function: OKAlyxLOGINButton
		function OKAlyxLOGINButtonPushed(app, event)
			app.alyxGUIUIFigure.Pointer = 'watch';
			app.OKAlyxLOGINButton.Text = '.';drawnow;

			if ~isa(app.alyx,'alyxManager')
				alyx = alyxManager;
			end
			alyx = app.alyx;
			alyx.user = app.alyxUser.Value;
			alyx.baseURL = app.alyxIP.Value;

			% this checks for AWS_ID, AWS_KEY and Alyx Password secrets
			% stored locally
			setSecrets(alyx);

			login(alyx);
			WaitSecs(0.1);

			if alyx.loggedIn
				app.OKAlyxLOGINButton.FontColor = [0 0.6 0];
				app.OKAlyxLOGINButton.Text = '..procedures';drawnow;
				try
					procedures = alyx.getData('procedures');
					procedures = {procedures(:).name};
					app.OKAlyxProcedure.Items = procedures;
					idx = find(contains(procedures,'Behavior training'));
					if ~isempty(idx); app.OKAlyxProcedure.ValueIndex = idx(1); end
				end
				app.OKAlyxLOGINButton.Text = '...projects';drawnow;
				try
					projects = alyx.getData('projects');
					projects = {projects(:).name};
					app.OKAlyxProject.Items = projects;
				end
				app.OKAlyxLOGINButton.Text = '....brain-regions';drawnow;
				if length(app.OKAlyxBrainRegion.Items) < 10
					try
						regions = alyx.getData('brain-regions');
						regions = {regions(:).name};
						app.OKAlyxBrainRegion.Items = regions;
						idx = find(contains(regions,'Cerebral cortex'));
						if ~isempty(idx); app.OKAlyxBrainRegion.ValueIndex = idx(1); end
					end
				end
				app.OKAlyxLOGINButton.BackgroundColor = [0.8 1 0.8];
				app.OKAlyxLOGINButton.Text = 'LOGGED IN ✔';
				app.OKAlyxRequestButton.Enable = "on";
				app.OKAlyxRequestButton.BackgroundColor = [0.8 1 0.8];
				app.AlyxAddWeight.Enable = "on";
				app.AlyxAddWater.Enable = "on";
				app.SubjectWeight.Enable = "on";
				app.SubjectWater.Enable = "on";
			else
				warning('Couldn''t login to Alyx, please check network');
				app.OKAlyxLOGINButton.FontColor = [0 0 0];
				app.OKAlyxLOGINButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxLOGINButton.Text = 'LOGIN';
				app.OKAlyxRequestButton.Enable = "off";
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.AlyxAddWeight.Enable = "off";
				app.AlyxAddWater.Enable = "off";
				app.SubjectWeight.Enable = "off";
				app.SubjectWater.Enable = "off";
			end
			app.alyxGUIUIFigure.Pointer = 'arrow';
		end

		% Button pushed function: OKAlyxLOGOUTButton
		function OKAlyxLOGOUTButtonPushed(app, event)
			if isa(app.alyx,'alyxManager')
				logout(app.alyx);
				app.OKAlyxLOGINButton.FontColor = [0 0 0];
				app.OKAlyxLOGINButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxLOGINButton.Text = 'LOGIN';
				app.OKAlyxRequestButton.Enable = "off";
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.AlyxAddWeight.Enable = "off";
				app.AlyxAddWater.Enable = "off";
				app.SubjectWeight.Enable = "off";
				app.SubjectWater.Enable = "off";
				app.CageLabGUIUIFigure.Pointer = 'arrow';
			end
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.alyxGUIUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {677, 677};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {410, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.alyxGUIUIFigure = uifigure('Visible', 'off');
			app.alyxGUIUIFigure.Name = 'alyxGUI';
			app.alyxGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.alyxGUIUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.LeftPanel);
			app.Image = uiimage(app.GridLayout2);
			app.Image.ImageSource = 'cebsit.png';
			app.CEBSITAlyxDatabaseInterfaceV10Label = uilabel(app.GridLayout2);
			app.CEBSITAlyxDatabaseInterfaceV10Label.Text = 'CEBSIT Alyx Database Interface V1.0';
			app.OKSubject = uieditfield(app.GridLayout2, 'text');
			app.OKSubject.Tag = 'OKTrainingName';
			app.OKSubject.Tooltip = {'Unique Subject Name / ID'};
			app.OKSubject.Value = 'Simulcra';
			app.text209 = uilabel(app.GridLayout2);
			app.text209.Tag = 'text209';
			app.text209.Text = 'Subject🧠';
			app.OKResearcher = uieditfield(app.GridLayout2, 'text');
			app.OKResearcher.Tag = 'OKTrainingResearcherName';
			app.OKResearcher.Tooltip = {'Experimenter name'};
			app.OKResearcher.Value = 'Jane Doe';
			app.text4554 = uilabel(app.GridLayout2);
			app.text4554.Tag = 'text4554';
			app.text4554.Text = 'Researcher🧑🏻‍🔬';
			app.OKLabName = uieditfield(app.GridLayout2, 'text');
			app.OKLabName.Tag = 'OKTrainingName';
			app.OKLabName.Tooltip = {'Lab / Group name'};
			app.OKLabName.Value = 'Lab';
			app.text209_2 = uilabel(app.GridLayout2);
			app.text209_2.Tag = 'text209';
			app.text209_2.Text = 'Lab🥼';
			app.OKLabLocation = uieditfield(app.GridLayout2, 'text');
			app.OKLabLocation.Tag = 'OKTrainingResearcherName';
			app.OKLabLocation.Tooltip = {'Room / building where experiment is being performed'};
			app.OKLabLocation.Value = 'Room 10';
			app.text4554_2 = uilabel(app.GridLayout2);
			app.text4554_2.Tag = 'text4554';
			app.text4554_2.Text = 'Location🗺️';
			app.text4554_3 = uilabel(app.GridLayout2);
			app.text4554_3.Text = 'ALYX IP & Port';
			app.text4554_4 = uilabel(app.GridLayout2);
			app.text4554_4.Text = 'ALYX User';
			app.OKAlyxUser = uieditfield(app.GridLayout2, 'text');
			app.OKAlyxUser.Value = 'admin';
			app.OKAlyxIP = uieditfield(app.GridLayout2, 'text');
			app.OKAlyxIP.Value = 'http://172.16.102.30:8000';
			app.OKAlyxProcedure = uidropdown(app.GridLayout2);
			app.OKAlyxProcedure.Items = {'Behavior training/tasks', 'Imaging', 'Intraocular injection', 'Optical stimulation'};
			app.OKAlyxProcedure.Value = 'Behavior training/tasks';
			app.OKAlyxProject = uidropdown(app.GridLayout2);
			app.OKAlyxProject.Items = {'Default', 'Visual testing', 'Touch Task', 'Saccade Task'};
			app.OKAlyxProject.Value = 'Default';
			app.text4554_5 = uilabel(app.GridLayout2);
			app.text4554_5.Text = 'Project';
			app.text4554_6 = uilabel(app.GridLayout2);
			app.text4554_6.Text = 'Procedure';
			app.OKAlyxBrainRegion = uidropdown(app.GridLayout2);
			app.OKAlyxBrainRegion.Items = {'Cerebral cortex', 'Thalamus', 'Hippocampus', 'Basal Ganglia'};
			app.OKAlyxBrainRegion.Value = 'Cerebral cortex';
			app.text4554_7 = uilabel(app.GridLayout2);
			app.text4554_7.Text = 'Brain Area';
			app.TaskProtocolEditFieldLabel = uilabel(app.GridLayout2);
			app.TaskProtocolEditFieldLabel.Text = 'Task Protocol';
			app.OKAlyxTaskProtocol = uieditfield(app.GridLayout2, 'text');
			app.OKAlyxLOGOUTButton = uibutton(app.GridLayout2, 'push');
			app.OKAlyxLOGOUTButton.ButtonPushedFcn = createCallbackFcn(app, @OKAlyxLOGOUTButtonPushed, true);
			app.OKAlyxLOGOUTButton.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.off.svg');
			app.OKAlyxLOGOUTButton.Text = 'Log out';
			app.OKAlyxLOGINButton = uibutton(app.GridLayout2, 'push');
			app.OKAlyxLOGINButton.ButtonPushedFcn = createCallbackFcn(app, @OKAlyxLOGINButtonPushed, true);
			app.OKAlyxLOGINButton.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.svg');
			app.OKAlyxLOGINButton.Text = 'Login';
			app.FolderListListBoxLabel = uilabel(app.GridLayout2);
			app.FolderListListBoxLabel.Text = 'Folder List';
			app.FolderListListBox = uilistbox(app.GridLayout2);
			app.FolderListListBox.Items = {};
			app.FolderListListBox.Value = {};
			app.OKAlyxSelectFolders = uibutton(app.GridLayout2, 'push');
			app.OKAlyxSelectFolders.Icon = fullfile(pathToMLAPP, 'images', 'open.svg');
			app.OKAlyxSelectFolders.Text = 'Select Folders';
			app.OKAlyxUpload = uibutton(app.GridLayout2, 'push');
			app.OKAlyxUpload.Icon = fullfile(pathToMLAPP, 'images', 'send.svg');
			app.OKAlyxUpload.Text = 'Upload';
			app.OKDataRepo = uieditfield(app.GridLayout2, 'text');
			app.OKDataRepo.Value = 'http://172.16.102.77:9000';
			app.OKDataBucket = uieditfield(app.GridLayout2, 'text');
			app.OKDataBucket.Value = 'cognitionplatform';
			app.text4554_8 = uilabel(app.GridLayout2);
			app.text4554_8.Text = 'Data Repository URL';
			app.text4554_9 = uilabel(app.GridLayout2);
			app.text4554_9.Text = 'Data-Repository Name';
			app.DefaultValuesLabel = uilabel(app.GridLayout2);
			app.DefaultValuesLabel.Text = 'Default Values:';
			app.PasswordReset = uidropdown(app.GridLayout2);
			app.PasswordReset.Items = {'Set Alyx Password', 'Set S3 ID', 'Set S3 KEY'};
			app.PasswordReset.Value = 'Set Alyx Password';
			app.ChangeButton = uibutton(app.GridLayout2, 'push');
			app.ChangeButton.Text = 'Change';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.RightPanel);
			app.UITable = uitable(app.GridLayout3);
			app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
			app.UITable.RowName = {};
			app.text209_3 = uilabel(app.GridLayout3);
			app.text209_3.Tag = 'text209';
			app.text209_3.Text = 'Alyx Results';
			app.OKAlyxRequestButton = uibutton(app.GridLayout3, 'push');
			app.OKAlyxRequestButton.Icon = fullfile(pathToMLAPP, 'images', 'send.svg');
			app.OKAlyxRequestButton.Text = 'Request';
			app.OKAlyxRequest = CompactEditableComboBox(app.GridLayout3);
			% Show the figure after all components are created
			app.alyxGUIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = alyxGUI

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.alyxGUIUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.alyxGUIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.alyxGUIUIFigure)
		end
	end
end