classdef opticka_ui < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		OKRoot                          matlab.ui.Figure
		OKMenuFile                      matlab.ui.container.Menu
		OKMenuNewProtocol               matlab.ui.container.Menu
		OKMenuOpen                      matlab.ui.container.Menu
		OKMenuSaveCopy                  matlab.ui.container.Menu
		OKMenuSave                      matlab.ui.container.Menu
		OKMenuSaveAsData                matlab.ui.container.Menu
		OKMenuStateInfo                 matlab.ui.container.Menu
		OKMenuQuit                      matlab.ui.container.Menu
		OKMenuEdit                      matlab.ui.container.Menu
		OKMenuCut                       matlab.ui.container.Menu
		OKMenuCopy                      matlab.ui.container.Menu
		OKMenuPaste                     matlab.ui.container.Menu
		OKMenuTools                     matlab.ui.container.Menu
		OKMenuGrating                   matlab.ui.container.Menu
		OKMenuColourGrating             matlab.ui.container.Menu
		OKMenuGabor                     matlab.ui.container.Menu
		OKMenuLogGabor                  matlab.ui.container.Menu
		OKMenuBar                       matlab.ui.container.Menu
		OKMenuDots                      matlab.ui.container.Menu
		OKMenuNDots                     matlab.ui.container.Menu
		OKMenuPicture                   matlab.ui.container.Menu
		OKMenuMovie                     matlab.ui.container.Menu
		OKMenuDisc                      matlab.ui.container.Menu
		OKMenuSpot                      matlab.ui.container.Menu
		OKMenuFixationCross             matlab.ui.container.Menu
		OKMenuTargetInducer             matlab.ui.container.Menu
		OKMenuPlaid                     matlab.ui.container.Menu
		OKMenuNoiseTexture              matlab.ui.container.Menu
		OKMenuLineTexture               matlab.ui.container.Menu
		OKReverseCorrelation            matlab.ui.container.Menu
		RunMenu                         matlab.ui.container.Menu
		OKRunTrainingSession            matlab.ui.container.Menu
		OKRunExperiment                 matlab.ui.container.Menu
		OKRunRFMapper                   matlab.ui.container.Menu
		OKMenuIO                        matlab.ui.container.Menu
		OKuseEyelink                    matlab.ui.container.Menu
		OKuseTobii                      matlab.ui.container.Menu
		OKuseLabJackTStrobe             matlab.ui.container.Menu
		OKuseDisplayPP                  matlab.ui.container.Menu
		OKuseDataPixx                   matlab.ui.container.Menu
		OKuseLabJackStrobe              matlab.ui.container.Menu
		OKuseArduino                    matlab.ui.container.Menu
		OKuseLabJackReward              matlab.ui.container.Menu
		OKuseEyeOccluder                matlab.ui.container.Menu
		OKuseMagStim                    matlab.ui.container.Menu
		OKMenuPreferences               matlab.ui.container.Menu
		OKMenuCalibrateLuminance        matlab.ui.container.Menu
		OKMenuCalibrateSize             matlab.ui.container.Menu
		OKMenuLoadGamma                 matlab.ui.container.Menu
		OKMenuSaveGamma                 matlab.ui.container.Menu
		OKMenumanageCode                matlab.ui.container.Menu
		OKMenuEditConfiguration         matlab.ui.container.Menu
		OKMenuLogs                      matlab.ui.container.Menu
		OKMenuTimingLog                 matlab.ui.container.Menu
		OKMenuTaskLog                   matlab.ui.container.Menu
		OKMenuBehaviourLog              matlab.ui.container.Menu
		OKMenuStateLog                  matlab.ui.container.Menu
		OKMenuShowGammaPlots            matlab.ui.container.Menu
		OKMenurfMapperLog               matlab.ui.container.Menu
		HelpMenu                        matlab.ui.container.Menu
		HomepageMenu                    matlab.ui.container.Menu
		CodeDocumentationMenu           matlab.ui.container.Menu
		KeyboardMappingsMenu            matlab.ui.container.Menu
		OKToolbar                       matlab.ui.container.Toolbar
		OKToolbarInitialise             matlab.ui.container.toolbar.PushTool
		OKToolbarOpen                   matlab.ui.container.toolbar.PushTool
		OKToolbarSave                   matlab.ui.container.toolbar.PushTool
		OKToolbarDefinePath             matlab.ui.container.toolbar.PushTool
		OKToggleUI                      matlab.ui.container.toolbar.PushTool
		OKStartRFMapper                 matlab.ui.container.toolbar.PushTool
		OKStartTask                     matlab.ui.container.toolbar.PushTool
		OKStartMOC                      matlab.ui.container.toolbar.PushTool
		OKToolbarStop                   matlab.ui.container.toolbar.PushTool
		OKToolbarAbort                  matlab.ui.container.toolbar.PushTool
		OKPanelTellOmniplex             matlab.ui.container.toolbar.PushTool
		OKPanelReconnectOmniplex        matlab.ui.container.toolbar.PushTool
		OKPanelSendOmniplex             matlab.ui.container.toolbar.PushTool
		OKPanelPingOmniplex             matlab.ui.container.toolbar.PushTool
		OKPanelDisconnectOmniplex       matlab.ui.container.toolbar.PushTool
		OKToolbarToggleRemote           matlab.ui.container.toolbar.ToggleTool
		Image                           matlab.ui.control.Image
		TabGroup                        matlab.ui.container.TabGroup
		DisplayTab                      matlab.ui.container.Tab
		GridLayout                      matlab.ui.container.GridLayout
		TaskOptionstaskSequenceMethodofConstantsMOCTimingsPanel  matlab.ui.container.Panel
		GridLayout2                     matlab.ui.container.GridLayout
		OKStartIndex                    matlab.ui.control.EditField
		text9_2                         matlab.ui.control.Label
		OKTrialProbability              matlab.ui.control.EditField
		TrialProbabilityEditFieldLabel  matlab.ui.control.Label
		OKTrialValues                   matlab.ui.control.EditField
		TrialValuesEditFieldLabel       matlab.ui.control.Label
		OKBlockProbability              matlab.ui.control.EditField
		BlockProbabilityEditFieldLabel  matlab.ui.control.Label
		OKBlockValues                   matlab.ui.control.EditField
		BlockValuesEditFieldLabel       matlab.ui.control.Label
		OKRandomise                     matlab.ui.control.CheckBox
		OKrandomGenerator               matlab.ui.control.DropDown
		text105                         matlab.ui.control.Label
		OKRandomSeed                    matlab.ui.control.EditField
		text106                         matlab.ui.control.Label
		OKnBlocks                       matlab.ui.control.EditField
		OKisTime                        matlab.ui.control.EditField
		text9                           matlab.ui.control.Label
		text10                          matlab.ui.control.Label
		OKibTime                        matlab.ui.control.EditField
		text104                         matlab.ui.control.Label
		OKtrialTime                     matlab.ui.control.EditField
		text107                         matlab.ui.control.Label
		OKrealTime                      matlab.ui.control.CheckBox
		OKUseFixation                   matlab.ui.control.CheckBox
		OKUseDummy                      matlab.ui.control.CheckBox
		OKUseRetina                     matlab.ui.control.CheckBox
		text102                         matlab.ui.control.Label
		OKMonitorDistance               matlab.ui.control.EditField
		OKpixelsPerCm                   matlab.ui.control.EditField
		text3                           matlab.ui.control.Label
		text4                           matlab.ui.control.Label
		text5                           matlab.ui.control.Label
		OKWindowSize                    matlab.ui.control.EditField
		OKscreenXOffset                 matlab.ui.control.EditField
		OKscreenYOffset                 matlab.ui.control.EditField
		text6                           matlab.ui.control.Label
		text7                           matlab.ui.control.Label
		OKOpenGLBlending                matlab.ui.control.CheckBox
		text83                          matlab.ui.control.Label
		OKSelectScreen                  matlab.ui.control.DropDown
		text100                         matlab.ui.control.Label
		text101                         matlab.ui.control.Label
		OKHideFlash                     matlab.ui.control.CheckBox
		OKUsePhotoDiode                 matlab.ui.control.CheckBox
		OKVerbose                       matlab.ui.control.CheckBox
		OKDebug                         matlab.ui.control.CheckBox
		OKGLSrc                         matlab.ui.control.DropDown
		OKGLDst                         matlab.ui.control.DropDown
		OKAntiAliasing                  matlab.ui.control.EditField
		OKNativeBeamPosition            matlab.ui.control.CheckBox
		OKrecordMovie                   matlab.ui.control.CheckBox
		OKUseGamma                      matlab.ui.control.DropDown
		text189                         matlab.ui.control.Label
		OKlogFrames                     matlab.ui.control.CheckBox
		text191                         matlab.ui.control.Label
		OKbitDepth                      matlab.ui.control.DropDown
		OKverbosityLevel                matlab.ui.control.EditField
		OKbenchmark                     matlab.ui.control.CheckBox
		text203                         matlab.ui.control.Label
		OKbackgroundColour              matlab.ui.control.EditField
		text8                           matlab.ui.control.Label
		TaskTab                         matlab.ui.container.Tab
		GridLayout3                     matlab.ui.container.GridLayout
		OKTrainingFileName              matlab.ui.control.Label
		OKRefreshStateInfo              matlab.ui.control.Button
		OKTrainingText                  matlab.ui.control.TextArea
		text4554                        matlab.ui.control.Label
		OKTrainingResearcherName        matlab.ui.control.EditField
		OKEditStateFileButon            matlab.ui.control.Button
		OKLoadStateButton               matlab.ui.control.Button
		text209                         matlab.ui.control.Label
		OKTrainingName                  matlab.ui.control.EditField
		OthersTab                       matlab.ui.container.Tab
		OtherSettingsPanel              matlab.ui.container.Panel
		OKStrobemodeLabel               matlab.ui.control.Label
		OKdPPMode                       matlab.ui.control.EditField
		OKArduinoportLabel              matlab.ui.control.Label
		OKEyeoccluderLabel              matlab.ui.control.Label
		OKarduinoPort                   matlab.ui.control.EditField
		text208                         matlab.ui.control.Label
		OKeyeOccluder                   matlab.ui.control.EditField
		OKOmniplexEnable                matlab.ui.control.CheckBox
		text185                         matlab.ui.control.Label
		OKOmniplexPort                  matlab.ui.control.EditField
		OKOmniplexIP                    matlab.ui.control.EditField
		DefaultEyetrackersettingscanbeoverriddenbystatemachinefilePanel  matlab.ui.container.Panel
		TabGroup2                       matlab.ui.container.TabGroup
		TobiiProTab                     matlab.ui.container.Tab
		OKAutopaceSwitch                matlab.ui.control.RockerSwitch
		AutopaceSwitchLabel             matlab.ui.control.Label
		OKReloadpreviousSwitch          matlab.ui.control.RockerSwitch
		ReloadpreviousSwitchLabel       matlab.ui.control.Label
		OKCalEditField                  matlab.ui.control.EditField
		CalEditFieldLabel               matlab.ui.control.Label
		OKValEditField                  matlab.ui.control.EditField
		ValEditFieldLabel               matlab.ui.control.Label
		OKCalibrationfileEditField      matlab.ui.control.EditField
		CalibrationfileLabel            matlab.ui.control.Label
		OKPaceDurationEditField         matlab.ui.control.NumericEditField
		OKeyeCALButton                  matlab.ui.control.Button
		OKManualcalibrationSwitch_Tobii  matlab.ui.control.RockerSwitch
		ManualcalibrationSwitch_2Label  matlab.ui.control.Label
		OKDrawEyeDropDown               matlab.ui.control.DropDown
		DrawEyeDropDownLabel            matlab.ui.control.Label
		OKStrictEditField_Tobii         matlab.ui.control.NumericEditField
		StrictEditField_2Label          matlab.ui.control.Label
		OKRadiusEditField_Tobii         matlab.ui.control.NumericEditField
		RadiusEditField_2Label          matlab.ui.control.Label
		OKDurationEditField_Tobii       matlab.ui.control.NumericEditField
		DurationEditField_2Label        matlab.ui.control.Label
		OKInitEditField_Tobii           matlab.ui.control.NumericEditField
		InitEditField_2Label            matlab.ui.control.Label
		OKYEditField_Tobii              matlab.ui.control.NumericEditField
		YEditField_2Label               matlab.ui.control.Label
		XOKEditField_Tobii              matlab.ui.control.NumericEditField
		XEditField_2Label               matlab.ui.control.Label
		OKSamplerateKnob                matlab.ui.control.DiscreteKnob
		SamplerateKnobLabel             matlab.ui.control.Label
		OKEyeusedDropDown               matlab.ui.control.DropDown
		EyeusedDropDownLabel            matlab.ui.control.Label
		OKTrackerDropDown               matlab.ui.control.DropDown
		TrackerDropDownLabel            matlab.ui.control.Label
		OKTrackingmodeDropDown          matlab.ui.control.DropDown
		TrackingmodeDropDownLabel       matlab.ui.control.Label
		OKCalibrationstimulusDropDown   matlab.ui.control.DropDown
		CalibrationstimulusDropDownLabel  matlab.ui.control.Label
		EyelinkTab                      matlab.ui.container.Tab
		CalibrationSettingsLabel        matlab.ui.control.Label
		OKCalibBeep                     matlab.ui.control.NumericEditField
		WaitLabel_2                     matlab.ui.control.Label
		OKCalibDevice                   matlab.ui.control.NumericEditField
		DeviceLabel                     matlab.ui.control.Label
		OKCalibWait                     matlab.ui.control.NumericEditField
		WaitLabel                       matlab.ui.control.Label
		OKCalibWidth                    matlab.ui.control.NumericEditField
		WidthLabel                      matlab.ui.control.Label
		OKCalibSize                     matlab.ui.control.NumericEditField
		SizeLabel                       matlab.ui.control.Label
		OKCalibColor                    matlab.ui.control.EditField
		CalibrationColorLabel           matlab.ui.control.Label
		OKCalibProp                     matlab.ui.control.EditField
		CalibrationPropEditFieldLabel   matlab.ui.control.Label
		OKManualcalibSwitch             matlab.ui.control.RockerSwitch
		ManualcalibrationSwitchLabel    matlab.ui.control.Label
		OKStrictEditField               matlab.ui.control.NumericEditField
		StrictEditFieldLabel            matlab.ui.control.Label
		OKCalibrationPointsKnob         matlab.ui.control.DiscreteKnob
		CalibrationPointsKnobLabel      matlab.ui.control.Label
		OKSampleRateKnob                matlab.ui.control.DiscreteKnob
		SampleRateKnobLabel             matlab.ui.control.Label
		OKRadiusEditField               matlab.ui.control.NumericEditField
		RadiusEditFieldLabel            matlab.ui.control.Label
		OKDurationEditField             matlab.ui.control.NumericEditField
		DurationEditFieldLabel          matlab.ui.control.Label
		OKInitEditField                 matlab.ui.control.NumericEditField
		InitEditFieldLabel              matlab.ui.control.Label
		OKYEditField                    matlab.ui.control.NumericEditField
		YEditFieldLabel                 matlab.ui.control.Label
		OKXEditField                    matlab.ui.control.NumericEditField
		XEditFieldLabel                 matlab.ui.control.Label
		OKIgnoreBlinksSwitch            matlab.ui.control.RockerSwitch
		IgnoreBlinksSwitchLabel         matlab.ui.control.Label
		ProtocolsPanel                  matlab.ui.container.Panel
		OKProtocolsList                 matlab.ui.control.ListBox
		OKProtocolDelete                matlab.ui.control.Button
		OKProtocolSave                  matlab.ui.control.Button
		OKProtocolLoad                  matlab.ui.control.Button
		OKPanelIndividual               matlab.ui.container.ButtonGroup
		OKOptickaVersion                matlab.ui.control.Label
		OKPanelVarList                  matlab.ui.container.ButtonGroup
		OKVarList                       matlab.ui.control.ListBox
		OKInspectVariable               matlab.ui.control.Button
		OKEditVariable                  matlab.ui.control.Button
		OKCopyVariable                  matlab.ui.control.Button
		OKDeleteVariable                matlab.ui.control.Button
		OKAddVariable                   matlab.ui.control.Button
		OKPanelVariables                matlab.ui.container.Panel
		OKVariableList                  matlab.ui.control.ListBox
		OKVariableOffset                matlab.ui.control.EditField
		OKVariableName                  matlab.ui.control.EditField
		OKVariableValues                matlab.ui.control.EditField
		OKVariableStimuli               matlab.ui.control.EditField
		text178                         matlab.ui.control.Label
		OKCopyVariableNameValues        matlab.ui.control.Button
		OKVariablesLog                  matlab.ui.control.Button
		OKVariablesLinear               matlab.ui.control.Button
		text142                         matlab.ui.control.Label
		text141                         matlab.ui.control.Label
		text140                         matlab.ui.control.Label
		OKPanelStimulusList             matlab.ui.container.ButtonGroup
		OKStimulusRunSingle             matlab.ui.control.Button
		OKStimList                      matlab.ui.control.ListBox
		OKStimulusRunAllBenchmark       matlab.ui.control.Button
		OKStimulusRunAll                matlab.ui.control.Button
		OKStimulusRunBenchmark          matlab.ui.control.Button
		OKStimulusRun                   matlab.ui.control.Button
		OKStimulusUp                    matlab.ui.control.Button
		OKStimulusDown                  matlab.ui.control.Button
		OKModifyStimulus                matlab.ui.control.Button
		OKInspectStimulus               matlab.ui.control.Button
		OKCopyStimulus                  matlab.ui.control.Button
		OKDeleteStimulus                matlab.ui.control.Button
		OKAddStimulus                   matlab.ui.control.Button
		OKOmniplexStatus                matlab.ui.control.Label
		OKPanelStimulus                 matlab.ui.container.Panel
		OKPanelStimulusText             matlab.ui.control.Label
	end


	properties (Access = public)
		o % opticka object
		el % eyelink settings
		tobii % tobii settings
	end

	properties (Access = public, Transient = true)
		viewerHandles % Description
	end

	methods (Access = private)
		function OKMenuCheckIO_Callback(app, hObject, eventdata, handles)
			% hObject    handle to OKMenuCheckIO (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')

			end
		end

		function OKRemoteIP_Callback(app, hObject, eventdata, handles)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		function OKRemotePort_Callback(app, hObject, eventdata, handles)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		function OKSerialPortName_Callback(app, hObject, eventdata, handles)
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		
		function clearMyHandles(app)
			delete(app.viewerHandles.f)
			app.viewerHandles = [];
		end
	end

	methods (Access = public)

		function OKTobiiUpdate(app)
			app.OKTrackerDropDownValueChanged();
		end
		
		function propertiesToVariables(app)
			if ~isempty(app.OKStimList.Value)
				v = find(strcmpi(app.OKStimList.Value,app.OKStimList.Items)==true);
				stim = app.o.r.stimuli{v};
				[myProps, myType] = stim.findAttributes('SetAccess','public');
				app.OKVariableList.Items;
				lst = {'xyPosition | type = double array'};
				for i = 1:length(myProps)
					if isempty(regexpi(myProps{i},'^(comment|name|animator|fullName)$','once'))
						lst{end+1} = [myProps{i} ' | type = ' myType{i}];
					end
				end
				lst = sort(lst);
				app.OKVariableList.Items = lst;
				app.OKVariableList.Value = lst{1};
			end
		end
		
		function showHelp(app,text)
			if isempty(text)
				text = "Welcome to Opticka";
			end
			if isempty(app.viewerHandles) || ~isgraphics(app.viewerHandles.f)
				app.viewerHandles = [];
				app.viewerHandles.f = uifigure('Name','Opticka Help',...
					'Units','pixels','Position',[0 0 640 400]);
				app.viewerHandles.html = uihtml(app.viewerHandles.f,'Position',[10 10 620 380]);
			end
			app.viewerHandles.html.HTMLSource = '';
			if isa(app.o,'opticka')
				whereami = app.o.paths.root;
			else
				whereami = which('opticka.m');
			end
			app.viewerHandles.html.HTMLSource = [whereami filesep 'help' filesep text];
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function opticka_Start(app, varargin)
			if nargin > 1 && isa(varargin{1},'opticka')
				app.o = varargin{1};
				if isdeployed
					app.OKRoot.Name = ['Opticka Experiment Manager [D] V' num2str(app.o.optickaVersion)];
				else
					app.OKRoot.Name = ['Opticka Experiment Manager V' num2str(app.o.optickaVersion)];
				end
				app.OKOptickaVersion.Text = app.OKRoot.Name;
			else
				warning('You need to pass an opticka object on startup to properly use this GUI...')
			end
			r = groot;
			app.OKRoot.Position(1) = (r.ScreenSize(3)) - app.OKRoot.Position(3);
			app.OKRoot.Position(2) = r.ScreenSize(4) - app.OKRoot.Position(4);
		end

		% Button pushed function: OKAddStimulus
		function OKAddStimulus_Callback(app, event)
			if isa(app.o, 'opticka')
				if isfield(app.o.store,'visibleStimulus')
					app.o.store.visibleStimulus.readPanel();
					if isempty(app.o.r.stimuli); app.o.r.stimuli = metaStimulus(); end
					app.o.r.stimuli{app.o.r.stimuli.n+1} = app.o.store.visibleStimulus.clone();
					app.o.addStimulus;
					if app.o.r.stimuli.n > 0
						app.OKAddStimulus.Enable = 'on';
						app.OKAddStimulus.FontWeight = 'Normal';
						app.OKAddStimulus.FontColor = [0 0 0];
						set(app.OKDeleteStimulus,'Enable','on');
						set(app.OKModifyStimulus,'Enable','on');
						set(app.OKCopyStimulus,'Enable','on');
						set(app.OKStimulusUp,'Enable','on');
						set(app.OKStimulusDown,'Enable','on');
						set(app.OKStimulusRun,'Enable','on');
						set(app.OKStimulusRunBenchmark,'Enable','on');
						set(app.OKStimulusRunAll,'Enable','on');
						set(app.OKStimulusRunAllBenchmark,'Enable','on');
						set(app.OKStimulusRunSingle,'Enable','on');
					end
				end
			end
		end

		% Button pushed function: OKAddVariable
		function OKAddVariable_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.addVariable;
				if app.o.r.task.nVars > 0
					set(app.OKDeleteVariable,'Enable','on');
					set(app.OKCopyVariable,'Enable','on');
					set(app.OKEditVariable,'Enable','on');
				end
			end
		end

		% Callback function
		function OKAntiAliasing_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Button pushed function: OKCopyStimulus
		function OKCopyStimulus_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKCopyStimulus (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				v = find(strcmpi(app.OKStimList.Value,app.OKStimList.Items)==true); %where are we in the stimulus list
				app.o.r.stimuli{app.o.r.stimuli.n+1} = app.o.r.stimuli{v}.clone;
				app.o.r.stimuli{app.o.r.stimuli.n}.name = ['Stimulus #' num2str(app.o.r.stimuli.n)];
				app.o.addStimulus;
			end
		end

		% Button pushed function: OKCopyVariableNameValues
		function OKCopyVariableNameValues_Callback(app, event)
			name = strtrim(strtok(app.OKVariableList.Value));
			set(app.OKVariableName,'Value',name);

			switch name
				case {'angle','direction','motionAngle'}
					string = num2str(-90:45:90);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(app.OKVariableValues,'Value',string);
				case {'colour','colour2','colourDisc','flashColour'}
					string = '{[0 0 0],[0.82 0 0],[0 0.58 0],[0 0 1],[1 1 1]}';
					set(app.OKVariableValues,'Value',string);
				case 'phase'
					string = num2str(0:22.5:180);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(app.OKVariableValues,'Value',string);
				case 'size'
					string = num2str([0 0.1 0.2 0.35 0.5 0.75 1 2 4 6 8]);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(app.OKVariableValues,'Value',string);
				case {'contrast','alpha','alpha2'}
					string = '[ 0 : 0.1 : 1 ]';
					set(app.OKVariableValues,'Value',string);
				case 'sf'
					string = num2str([0 0.1 0.5 0.7 1 1.5 2 3 4 5 6]);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(app.OKVariableValues,'Value',string);
				case 'tf'
					string = num2str([0.5 1 2 3 4 5]);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(app.OKVariableValues,'Value',string);
				case {'xPosition', 'yPosition'}
					string = num2str(-1:0.2:1);
					string = regexprep(string,'\s+',' '); %collapse spaces
					set(app.OKVariableValues,'Value',string);
				case 'xyPosition'
					string = '{[-10 0], [0 -10], [10 0], [0 10]}';
					set(app.OKVariableValues,'Value',string);
			end
		end

		% Callback function
		function OKCopyVariableName_Callback(app, event)
			set(app.OKVariableName,'Value','Test');
		end

		% Button pushed function: OKCopyVariable
		function OKCopyVariable_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.copyVariable;
			end
		end

		% Button pushed function: OKDeleteStimulus
		function OKDeleteStimulus_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.deleteStimulus;
				app.OKAddStimulus.FontColor = [0 0 0];
				app.OKAddStimulus.FontWeight = 'normal';
				if app.o.r.stimuli.n == 0
					app.OKStimList.Items = {'Select stimulus from menu, then Add to this panel...'};
					set(app.OKAddStimulus,'Enable','off');
					set(app.OKDeleteStimulus,'Enable','off');
					set(app.OKModifyStimulus,'Enable','off');
					set(app.OKCopyStimulus,'Enable','off');
					set(app.OKStimulusUp,'Enable','off');
					set(app.OKStimulusDown,'Enable','off');
					set(app.OKStimulusRun,'Enable','off');
					set(app.OKStimulusRunBenchmark,'Enable','off');
					set(app.OKStimulusRunAll,'Enable','off');
					set(app.OKStimulusRunAllBenchmark,'Enable','off');
					set(app.OKStimulusRunSingle,'Enable','off');
				end
			end
		end

		% Button pushed function: OKDeleteVariable
		function OKDeleteVariable_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.deleteVariable;
				if app.o.r.task.nVars < 1
					app.OKVarList.Items = {};
					app.OKDeleteVariable.Enable = 'off';
					app.OKCopyVariable.Enable = 'off';
					app.OKEditVariable.Enable = 'off';
				end
			end
		end

		% Button pushed function: OKEditStateFileButon
		function OKEditStateFileButon_Callback(app, event)
			if isa(app.o, 'opticka')
				if exist(app.o.r.paths.stateInfoFile,'file')
					if isdeployed || ismcc
						if ismac
							system(['open ''' app.o.r.paths.stateInfoFile ''''])
						elseif isunix
							system(['xdg-open ''' app.o.r.paths.stateInfoFile ''''])
						end
					else
						edit(app.o.r.paths.stateInfoFile);
					end
				end
				app.o.getStateInfo();
			end
		end

		% Button pushed function: OKEditVariable
		function OKEditVariable_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.editVariable;
				if app.o.r.task.nVars < 1
					app.OKVarList.Items = {};
					app.OKDeleteVariable.Enable = 'off';
					app.OKCopyVariable.Enable = 'off';
					app.OKEditVariable.Enable = 'off';
				end
			end
		end

		% Button pushed function: OKInspectStimulus
		function OKInspectStimulus_Callback(app, event)
			if isa(app.o, 'opticka')
				%v = find(strcmpi(app.OKStimList.Value,app.OKStimList.Items)==true);
				%if v > 0 && v <= app.o.r.stimuli.n
				%	uiinspect(app.o.r.stimuli{v});
				%end
				app.showHelp('uihelpstims.html');
			end
		end

		% Button pushed function: OKInspectVariable
		function OKInspectVariable_Callback(app, event)
			if isa(app.o, 'opticka')
				%v = find(strcmpi(app.OKVarList.Value,app.OKVarList.Items)==true);
				%if v > 0 && v <= app.o.r.task.nVars
				%	uiinspect(app.o.r.task.nVar(v));
				%end
				app.showHelp('uihelpvars.html');
			end
		end

		% Button pushed function: OKLoadStateButton
		function OKLoadStateButton_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.router('LoadStateInfo');
				app.o.getStateInfo();
			end
		end

		% Menu selected function: OKMenuTimingLog
		function OKMenuTimingLog_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.showTimingLog;
			end
		end

		% Menu selected function: OKMenuCalibrateLuminance
		function OKMenuCalibrateLuminance_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				v=str2num(app.OKSelectScreen.Value);
				if isa(app.o.r.screen.gammaTable,'calibrateLuminance')
					app.o.r.screen.gammaTable.run;
				else
					inp = struct('screen',v-1);
					c = calibrateLuminance(inp);
					app.o.r.screen.gammaTable = c;
					c.filename = [app.o.paths.calibration filesep 'Cal-' date '-' c.comments];
					app.o.saveCalibration;
				end
				set(app.OKUseGamma,'Items',{'None'; 'Gamma'; app.o.r.screen.gammaTable.analysisMethods});
			end
		end

		% Menu selected function: OKMenuCalibrateSize
		function OKMenuCalibrateSize_Callback(app, event)
			v=str2num(app.OKSelectScreen.Value);
			s=str2num(app.OKMonitorDistance.Value);
			[~,dpc]=calibrateSize(v-1,s);
			app.OKpixelsPerCm.Value = num2str(dpc);
		end

		% Menu selected function: OKMenuBar, OKMenuColourGrating, 
		% OKMenuDisc, OKMenuDots, OKMenuFixationCross, OKMenuGabor, 
		% OKMenuGrating, OKMenuLogGabor, OKMenuMovie, OKMenuNDots, 
		% OKMenuPicture, OKMenuSpot, OKMenuTargetInducer
		function OKAddStimulus_Panel(app, event)
			if isa(app.o, 'opticka')
				%if isfield(app.o.store,'visibleStimulus')
				%	app.o.store.visibleStimulus.closePanel();
				%	app.o.store = rmfield(app.o.store,'visibleStimulus');
				%end
				stim = event.Source.Tooltip{1};
				if isempty(stim); warning('Can''t find stimulus'); return; end
				app.OKPanelStimulusText.Text = ['Loading Stimulus Panel for ' stim '...']; drawnow;
				if ~isfield(app.o.store, stim)
					mycmd = [stim '(''name'',''' event.Source.Text ''');'];
					app.o.store.(stim)=eval(mycmd);
				end
				if isfield(app.o.store, 'visibleStimulus')
					app.o.store.visibleStimulus.hidePanel();
				end
				app.o.store.visibleStimulus = app.o.store.(stim);
				if app.o.store.visibleStimulus.isGUI
					app.o.store.visibleStimulus.showPanel();
				else
					app.o.store.visibleStimulus.makePanel(app.OKPanelStimulus);
				end
				app.OKAddStimulus.FontColor = [0.7 0 0];
				app.OKAddStimulus.FontWeight = 'bold';
				if isempty(app.o.r.stimuli) || app.o.r.stimuli.n == 0
					app.OKStimList.Items = {'Press add to add stimulus to list'};
				end
				app.OKAddStimulus.Enable = 'on';
				app.OKPanelStimulusText.Text = '';
			end
		end

		% Menu selected function: OKMenuLoadGamma
		function OKMenuLoadGamma_Callback(app, event)
			if isa(app.o, 'opticka')
				uiopen(app.o.paths.calibration);
				if exist('tmp','var') && isa(tmp,'calibrateLuminance')
					app.o.r.screen.gammaTable = tmp; clear tmp;
				elseif exist('c','var') && isa(c,'calibrateLuminance')
					app.o.r.screen.gammaTable = c; clear c;
				else
					app.o.r.screen.gammaTable = [];
				end
				if ~isempty(app.o.r.screen.gammaTable)
					app.OKUseGamma.Items = [{'None'}, {'Gamma'}, app.o.r.screen.gammaTable.analysisMethods(:)']';
					if length(app.OKUseGamma.Items) > 3
						app.OKUseGamma.Value = app.OKUseGamma.Items{3};
					end
				else
					app.OKUseGamma.Items = {'None'};
					app.OKUseGamma.Value = app.OKUseGamma.Items{1};
				end
			end
		end

		% Menu selected function: OKMenuNewProtocol
		function OKMenuNewProtocol_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r = runExperiment();
				initialise(app.o.r);
				app.o.store.evnt = [];
				app.o.store = rmfield(app.o.store,'evnt');
				app.o.store.visibleStimulus=[];
				app.o.store = rmfield(app.o.store,'visibleStimulus');
				app.o.clearStimulusList;
				app.o.clearVariableList;
				app.o.getScreenVals;
				app.o.getTaskVals;
			end
		end

		% Callback function: OKMenuOpen, OKToolbarOpen
		function OKProtocolOpen(app, event)
			if isa(app.o, 'opticka')
				app.o.router('loadProtocol',false);
			end
		end

		% Callback function: OKMenuQuit, OKRoot
		function OKMenuQuit_Callback(app, event)
			if isa(app.o, 'opticka')
				try
					app.o.savePrefs;
				catch ME
					fprintf('--->>> Opticka failed to save prefs on close...\n');
					rethrow(ME);
				end
			end
			delete(gcf);
		end

		% Menu selected function: OKMenuSaveGamma
		function OKMenuSaveGamma_Callback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.r.screen.gammaTable,'calibrateLuminance')
					c=app.o.r.screen.gammaTable;
					uisave('c',[app.o.paths.calibration filesep 'Calibration-' date '-' app.o.r.screen.gammaTable.comments]);
				else
					warndlg('No calibration was loaded...')
				end
			end
		end

		% Menu selected function: OKMenuShowGammaPlots
		function OKMenuShowGammaPlots_Callback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.r.screen.gammaTable,'calibrateLuminance')
					app.o.r.screen.gammaTable.plot;
				end
			end
		end

		% Menu selected function: OKMenuStateInfo
		function OKMenuStateInfo_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKMenuStateInfo (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				app.o.router('LoadStateInfo');
				app.o.getStateInfo();
			end
		end

		% Menu selected function: OKMenuTaskLog
		function OKMenuTaskLog_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.task.showLog;
			end
		end

		% Menu selected function: OKMenumanageCode
		function OKMenumanageCode_Callback(app, event)
			manageCode
		end

		% Button pushed function: OKModifyStimulus
		function OKModifyStimulus_Callback(app, event)
			if isa(app.o, 'opticka')
				v = get(app.OKStimList,'Value');
				if v > 0
					seditor(app.o.r.stimuli{v}, app.output);
				end

			end
		end

		% Callback function
		function OKMonitorDistance_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Callback function
		function OKNativeBeamPosition_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Value changed function: OKOmniplexEnable
		function OKOmniplexEnable_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKOmniplexEnable (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			% Hint: get(hObject,'Value') returns toggle state of OKOmniplexEnable
			if isa(app.o, 'opticka')
				if get(hObject,'Value') < 1
					set(app.OKOmniplexIP,'Enable','off');
					set(app.OKOmniplexPort,'Enable','off');
					set(app.OKPanelTellOmniplex,'Enable','off');
					set(app.OKPanelReconnectOmniplex,'Enable','off');
					set(app.OKPanelSendOmniplex,'Enable','off');
					set(app.OKPanelPingOmniplex,'Enable','off');
					set(app.OKPanelDisconnectOmniplex,'Enable','off');
				else
					set(app.OKOmniplexIP,'Enable','on');
					set(app.OKOmniplexPort,'Enable','on');
					set(app.OKPanelTellOmniplex,'Enable','on');
					set(app.OKPanelReconnectOmniplex,'Enable','on');
					set(app.OKPanelSendOmniplex,'Enable','on');
					set(app.OKPanelPingOmniplex,'Enable','on');
					set(app.OKPanelDisconnectOmniplex,'Enable','on');
				end
			end
		end

		% Clicked callback: OKPanelDisconnectOmniplex
		function OKPanelDisconnectOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				if isa(app.o.oc,'dataConnection')
					app.o.disconnectOmniplex;
				end
			end
		end

		% Clicked callback: OKPanelPingOmniplex
		function OKPanelPingOmniplex_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')

				rAddress = get(app.OKOmniplexIP,'Value');
				status = app.o.ping(rAddress);
				if status > 0
					set(app.o.h.OKOmniplexStatus,'Text','Omniplex: machine ping ERROR!')
				else
					if isa(app.o.oc,'dataConnection') && app.o.oc.isOpen == 1
						app.o.oc.write('--ping--');
						loop = 1;
						while loop < 8
							in = app.o.oc.read(0);
							fprintf('\n{opticka said: %s}\n',in)
							if regexpi(in,'ping')
								fprintf('\nWe can ping omniplex master on try: %d\n',loop)
								set(app.OKOmniplexStatus,'Text','Omniplex: connected and pinged')
								break
							else
								fprintf('\nOmniplex master not responding, try: %d\n',loop)
								set(app.OKOmniplexStatus,'Text','Omniplex: not responding')
							end
							loop=loop+1;
							pause(0.1);
						end
					end
				end
			end
		end

		% Clicked callback: OKPanelReconnectOmniplex
		function OKPanelReconnectOmniplex_ClickedCallback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.oc,'dataConnection')
					app.o.oc.closeAll;
					app.o.connectToOmniplex;
				end
			end
		end

		% Clicked callback: OKPanelSendOmniplex
		function OKPanelSendOmniplex_ClickedCallback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.oc,'dataConnection')
					app.o.sendOmniplexStimulus;
				end
			end
		end

		% Clicked callback: OKPanelTellOmniplex
		function OKPanelTellOmniplex_ClickedCallback(app, event)
			if isa(app.o, 'opticka')
				resp = questdlg('Is opxOnline running on the Omniplex machine?','Check OPX','No');
				if strcmpi(resp,'Yes')
					app.o.connectToOmniplex
				end
			end
		end

		% Button pushed function: OKProtocolDelete
		function OKProtocolDelete_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.router('deleteProtocol');
			end
		end

		% Callback function
		function OKProtocolDuplicate_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.router('duplicateProtocol');
			end
		end

		% Button pushed function: OKProtocolLoad
		function OKProtocolLoad_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.router('loadProtocol', true);
			end
		end

		% Callback function: OKMenuSave, OKProtocolSave
		function OKProtocolSaveOnly(app, event)
			if isa(app.o, 'opticka')
				app.o.router('saveProtocol');
			end
		end

		% Clicked callback: OKStartRFMapper
		function OKStartRFMapper_Callback(app, event)
			if isa(app.o, 'opticka')
				app.OKEyelinkSettings();
				app.o.store.rfLog = [];
				rf=rfMapper;
				rf.stimTime = str2double(app.OKtrialTime.Value);
				if app.o.r.useEyeLink; rf.useEyetracker = true; end
				if app.o.r.dummyMode==true; rf.dummyMode = true; else; rf.dummyMode = false; end
				rf.run(app.o.r);
				app.o.store.rfLog = rf;
				clear rf;
			end
		end

		% Value changed function: OKRandomSeed
		function OKRandomSeed_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Value changed function: OKRandomise
		function OKRandomise_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Button pushed function: OKRefreshStateInfo
		function OKRefreshStateInfo_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getStateInfo();
			end
		end

		% Callback function
		function OKSettingsmovieCodec_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.screen.movieSettings.codec=get(event.Source,'Value');
			end
		end

		% Callback function
		function OKSettingsmovieFrames_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.screen.movieSettings.nFrames=str2num(get(hObject,'Value')); %#ok<*ST2NM>
			end
		end

		% Callback function
		function OKSettingsmoviePrecision_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.screen.movieSettings.quality=get(event.Source,'Value');
			end
		end

		% Callback function
		function OKSettingsmovieSize_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.screen.movieSettings.size = str2num(get(event.Source,'Value'));
			end
		end

		% Callback function
		function OKSettingsmovieType_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.r.screen.movieSettings.type=get(event.Source,'Value');
			end
		end

		% Callback function: OKRunTrainingSession, OKStartTask
		function OKStartTask_Callback(app, event)
			if isa(app.o, 'opticka') && isa(app.o.r,'runExperiment')
				if ~isempty(regexp(app.o.comment, '^Protocol','once'))
					app.o.r.comment = app.o.comment;
				end
				if isempty(app.tobii)
					app.OKTobiiSettings();
				end
				app.o.r.tobiisettings = app.tobii;
				if isempty(app.el)
					app.OKEyelinkSettings();
				end
				app.o.r.elsettings = app.el;
				runTask(app.o.r);
			end
		end

		% Value changed function: OKStimList
		function OKStimList_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.editStimulus;
				app.OKAddStimulus.FontColor = [0 0 0];
				app.OKAddStimulus.FontWeight = 'normal';
				if ~isempty(app.OKStimList.Items)
					app.OKAddStimulus.Enable = true;
					app.OKDeleteStimulus.Enable = true;
					app.OKCopyStimulus.Enable = true;
				end
				propertiesToVariables(app);
			end
		end

		% Button pushed function: OKStimulusDown
		function OKStimulusDown_Callback(app, event)
			if isa(app.o, 'opticka')
				value = find(strcmpi(app.OKStimList.Items,app.OKStimList.Value)==true); %where are we in the stimulus list
				slength = app.o.r.stimuli.n;
				if isempty(value); return; end
				if value < slength
					idx = 1:slength;
					idx2 = idx;
					idx2(value) = idx2(value)+1;
					idx2(value+1) = idx2(value+1)-1;
					app.o.r.stimuli(idx) = app.o.r.stimuli(idx2);
					app.o.modifyStimulus();
					app.OKStimList.Value = app.OKStimList.Items{value+1};
				end
			end
		end

		% Button pushed function: OKStimulusRunAllBenchmark
		function OKStimulusRunAllBenchmark_Callback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.r.stimuli,'metaStimulus') && app.o.r.stimuli.n > 0
					app.o.r.stimuli.choice = [];
					if isa(app.o.r.screen,'screenManager')
						run(app.o.r.stimuli, true, 5, app.o.r.screen);
					else
						run(app.o.r.stimuli, true);
					end
				end
			end
		end

		% Button pushed function: OKStimulusRunAll
		function OKStimulusRunAll_Callback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.r.stimuli,'metaStimulus') && app.o.r.stimuli.n > 0
					app.o.r.stimuli.choice = [];
					if isa(app.o.r.screen,'screenManager')
						run(app.o.r.stimuli, [], str2num(app.OKtrialTime.Value), app.o.r.screen);
					else
						run(app.o.r.stimuli);
					end
				end
			end
		end

		% Button pushed function: OKStimulusRunBenchmark
		function OKStimulusRunBenchmark_Callback(app, event)
			if isa(app.o, 'opticka')
				v = find(strcmpi(app.OKStimList.Value,app.OKStimList.Items)==true); %where are we in the stimulus list
				if v > 0 && isa(app.o.r.stimuli{v},'baseStimulus')
					run(app.o.r.stimuli{v}, true, [], app.o.r.screen);
				end
			end
		end

		% Button pushed function: OKStimulusRunSingle
		function OKStimulusRunSingle_Callback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.r.stimuli,'metaStimulus') && app.o.r.stimuli.n > 0
					app.o.r.stimuli.choice = [];
					runSingle(app.o.r.stimuli, app.o.r.screen);
				end
			end
		end

		% Button pushed function: OKStimulusRun
		function OKStimulusRun_Callback(app, event)
			if isa(app.o, 'opticka')
				v = find(strcmpi(app.OKStimList.Value,app.OKStimList.Items)==true); %where are we in the stimulus list
				if v > 0 && isobject(app.o.r.stimuli{v})
					run(app.o.r.stimuli{v}, false, str2num(app.OKtrialTime.Value), app.o.r.screen, 0);
				end
				app.OKStimulusRunAll.Enable = 'on';
			end
		end

		% Button pushed function: OKStimulusUp
		function OKStimulusUp_Callback(app, event)
			if isa(app.o, 'opticka')
				value = find(strcmpi(app.OKStimList.Items,app.OKStimList.Value)==true); %where are we in the stimulus list
				slength = app.o.r.stimuli.n;
				if isempty(value); return; end
				if value > 1
					idx = 1:slength;
					idx2 = idx;
					idx2(value) = idx2(value)-1;
					idx2(value-1) = idx2(value-1)+1;
					app.o.r.stimuli(idx) = app.o.r.stimuli(idx2);
					app.o.modifyStimulus();
					app.OKStimList.Value = app.OKStimList.Items{value-1};
				end
			end
		end

		% Clicked callback: OKToggleUI
		function OKToggleUI_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToggleUI (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if strcmp(get(app.OKPanelGlobal,'Visible'),'on')
				set(app.OKPanelGlobal,'Visible','off');
				set(app.OKPanelProtocols,'Visible','on');
				set(app.OKPanelTraining,'Visible','off');
			elseif strcmp(get(app.OKPanelProtocols,'Visible'),'on')
				set(app.OKPanelProtocols,'Visible','off')
				set(app.OKPanelGlobal,'Visible','off')
				set(app.OKPanelTraining,'Visible','on')
				if isa(app.o, 'opticka')
					app.o.getStateInfo();
				end
			else
				set(app.OKPanelProtocols,'Visible','off')
				set(app.OKPanelGlobal,'Visible','on')
				set(app.OKPanelTraining,'Visible','off')
			end
		end

		% Clicked callback: OKToolbarAbort
		function OKToolbarAbort_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarAbort (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				app.o.r.uiCommand='abort';
				if isa(app.o.oc,'dataConnection')
					app.o.oc.write('--abort--');
				end
				drawnow;
			end
		end

		% Clicked callback: OKToolbarDefinePath
		function OKToolbarDefinePath_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarDefinePath (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				path = uigetdir;
				if ischar(path) && exist(path,'dir')
					initialiseSave(app.o, path);
					if isa(app.o.r,'runExperiment');
						initialiseSave(app.o.r, path);
					end
				end
			end
		end

		% Clicked callback: OKToolbarInitialise
		function OKToolbarInitialise_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarInitialise (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				app.o.r = [];
				app.o.store.evnt = [];
				app.o.store = rmfield(app.o.store,'evnt');
				app.o.store.visibleStimulus=[];
				app.o.store = rmfield(app.o.store,'visibleStimulus');
				app.o.clearStimulusList;
				app.o.clearVariableList;
				app.o.getScreenVals;
				app.o.getTaskVals;
			end
		end

		% Callback function
		function OKToolbarRun_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarRun (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)

			if isa(app.o, 'opticka')
				if ~isempty(app.o.oc) && app.o.oc.isOpen == 1 && app.o.r.useLabJack == 1
					app.o.oc.write('--GO!--');
					pause(0.5);
				end
				drawnow;
				app.o.r.uiCommand='run';
				app.o.r.run;
			end
		end

		% Clicked callback: OKToolbarStop
		function OKToolbarStop_ClickedCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarStop (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				app.o.r.uiCommand='stop';
				drawnow;
			end
		end

		% On callback: OKToolbarToggleRemote
		function OKToolbarToggleRemote_OnCallback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			% hObject    handle to OKToolbarToggleRemote (see GCBO)
			% eventdata  reserved - to be defined in a future version of MATLAB
			% handles    structure with handles and user data (see GUIDATA)
			if isa(app.o, 'opticka')
				eval(app.o.store.serverCommand)
			end
		end

		% Button pushed function: OKVariablesLinear
		function OKVariablesLinear_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
			values = str2num(get(app.OKVariableValues,'Value'));
			if length(values) == 3
				values=linspace(values(1),values(2),values(3));
				string = num2str(values);
			else
				string = num2str(values);
				string = regexprep(string,'\s+',' '); %collapse spaces
			end
			set(app.OKVariableValues,'Value',string);
		end

		% Button pushed function: OKVariablesLog
		function OKVariablesLog_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			values = str2num(get(app.OKVariableValues,'Value'));
			if length(values) == 3
				values=logspace(log10(values(1)),log10(values(2)),values(3));
				string = num2str(values);
				string = regexprep(string,'\s+',' '); %collapse spaces
				set(app.OKVariableValues,'Value',string);
			else
				warndlg('Please enter a minimum, maximum and number of values');
			end
		end

		% Value changed function: OKisTime
		function OKisTime_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Value changed function: OKibTime
		function OKibTime_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Callback function
		function OKlogFrames_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				if isa(app.o.r.screen,'screenManager')
					if get(hObject,'Value') == 1
						set(app.OKbenchmark,'Value',0)
					end
					app.o.getScreenVals;
				end
			end
		end

		% Value changed function: OKnBlocks
		function OKnBlocks_Callback(app, event)
			% Create GUIDE-style callback args - Added by Migration Tool
			[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Value changed function: OKrandomGenerator
		function OKrandomGenerator_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Value changed function: OKrealTime
		function OKrealTime_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Value changed function: OKrecordMovie
		function OKrecordMovie_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Value changed function: OKtrialTime
		function OKtrialTime_Callback(app, event)
			if isa(app.o, 'opticka')
				app.o.getTaskVals;
			end
		end

		% Menu selected function: OKuseDataPixx
		function OKuseDataPixx_Callback(app, event)
			if event.Source.Checked == false
				event.Source.Checked = true;
				app.OKuseLabJackStrobe.Checked = false;
				app.OKuseLabJackTStrobe.Checked = false;
				app.OKuseDisplayPP.Checked = false;
			else
				event.Source.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseEyelink
		function OKuseEyelink_Callback(app, event)
			if event.Source.Checked == true
				event.Source.Checked = false;
			else
				event.Source.Checked = true;
				app.OKuseTobii.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseEyeOccluder
		function OKuseEyeOccluder_Callback(app, event)
			if isa(app.o, 'opticka')
				if get(hObject,'Value') == 1 && get(app.OKuseDataPixx,'Value') == 1
					set(app.OKuseDataPixx,'Value', 0)
				end
				app.o.getScreenVals;
			end
		end

		% Callback function
		function OKverbosityLevel_Callback(app, event)
			if isa(app.o, 'opticka')
				if isa(app.o.r.screen,'screenManager')
					app.o.r.screen.verbosityLevel = str2double(get(hObject,'Value'));
				end
			end
		end

		% Value changed function: OKAntiAliasing, OKDebug, OKGLDst, 
		% OKGLSrc, OKHideFlash, OKMonitorDistance, OKNativeBeamPosition, 
		% OKOpenGLBlending, OKSelectScreen, OKUseDummy, OKUseFixation, 
		% OKUseGamma, OKUsePhotoDiode, OKUseRetina, OKVerbose, 
		% OKWindowSize, OKbackgroundColour, OKbenchmark, OKbitDepth, 
		% OKlogFrames, OKpixelsPerCm, OKscreenXOffset, OKscreenYOffset, 
		% OKverbosityLevel
		function OKGetScreenVals(app, event)
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseTobii
		function OKuseTobiiMenuSelected(app, event)
			if event.Source.Checked == true
				event.Source.Checked = false;
			else
				event.Source.Checked = true;
				app.OKuseEyelink.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseLabJackTStrobe
		function OKuseLabJackTStrobeMenuSelected(app, event)
			if event.Source.Checked == false
				event.Source.Checked = true;
				app.OKuseLabJackStrobe.Checked = false;
				app.OKuseDataPixx.Checked = false;
				app.OKuseDisplayPP.Checked = false;
			else
				event.Source.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseDisplayPP
		function OKuseDisplayPPMenuSelected(app, event)
			if event.Source.Checked == false
				event.Source.Checked = true;
				app.OKuseLabJackStrobe.Checked = false;
				app.OKuseDataPixx.Checked = false;
				app.OKuseLabJackTStrobe.Checked = false;
			else
				event.Source.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseLabJackStrobe
		function OKuseLabJackStrobeMenuSelected(app, event)
			if event.Source.Checked == false
				event.Source.Checked = true;
				app.OKuseLabJackTStrobe.Checked = false;
				app.OKuseDataPixx.Checked = false;
				app.OKuseDisplayPP.Checked = false;
			else
				event.Source.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseArduino
		function OKuseArduinoMenuSelected(app, event)
			if event.Source.Checked == false
				event.Source.Checked = true;
				app.OKuseLabJackReward.Checked = false;
			else
				event.Source.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKuseLabJackReward
		function OKuseLabJackRewardMenuSelected(app, event)
			if event.Source.Checked == false
				event.Source.Checked = true;
				app.OKuseArduino.Checked = false;
			else
				event.Source.Checked = false;
			end
			if isa(app.o, 'opticka')
				app.o.getScreenVals;
			end
		end

		% Menu selected function: OKMenuStateLog
		function OKMenuStateLogSelected(app, event)
			if isa(app.o, 'opticka')
				app.o.r.stateMachine.showLog();
			end
		end

		% Menu selected function: OKMenuBehaviourLog
		function OKMenuBehaviourLogSelected(app, event)
			if isa(app.o, 'opticka')
				if ~isempty(app.o.r.behaviouralRecord) && isa(app.o.r.behaviouralRecord,'behaviouralRecord')
					app.o.r.behaviouralRecord.plot();
				end
			end
		end

		% Callback function: OKRunExperiment, OKStartMOC
		function OKStartMOC_Callback(app, event)
			if isa(app.o, 'opticka') && isa(app.o.r,'runExperiment')
				if ~isempty(regexp(app.o.comment, '^Protocol','once'))
					app.o.r.comment = app.o.comment;
				end
				runMOC(app.o.r);
			end
		end

		% Value changed function: OKCalibBeep, OKCalibColor, 
		% OKCalibDevice, OKCalibProp, OKCalibSize, OKCalibWait, 
		% OKCalibWidth, OKCalibrationPointsKnob, OKDurationEditField, 
		% OKIgnoreBlinksSwitch, OKInitEditField, OKManualcalibSwitch, 
		% OKRadiusEditField, OKSampleRateKnob, OKStrictEditField, 
		% OKXEditField, OKYEditField
		function OKEyelinkSettings(app, event)
			app.el.sampleRate = str2num(app.OKSampleRateKnob.Value);
			app.el.calibrationStyle = app.OKCalibrationPointsKnob.Value;
			app.el.calibrationProportion = str2num(app.OKCalibProp.Value);
			if matches(app.OKManualcalibSwitch.Value,'Off')
				app.el.remoteCalibration = false;
			else
				app.el.remoteCalibration = true;
			end
			if matches(app.OKIgnoreBlinksSwitch.Value,'Off')
				app.el.ignoreBlinks = false;
			else
				app.el.ignoreBlinks = true;
			end
			app.el.fixation.X = app.OKXEditField.Value;
			app.el.fixation.Y = app.OKYEditField.Value;
			app.el.fixation.initTime = app.OKInitEditField.Value;
			app.el.fixation.time = app.OKDurationEditField.Value;
			app.el.fixation.radius = app.OKRadiusEditField.Value;
			app.el.fixation.strict = logical(app.OKStrictEditField.Value);
			app.el.modfy.calibrationtargetcolour = str2num(app.OKCalibColor.Value);
			app.el.modify.calibrationtargetsize = app.OKCalibSize.Value;
			app.el.modify.calibrationtargetwidth = app.OKCalibWidth.Value;
			app.el.modify.waitformodereadytime	= app.OKCalibWait.Value;
			app.el.modify.devicenumber 			= app.OKCalibDevice.Value;
			app.el.modify.targetbeep 			= app.OKCalibBeep.Value;

		end

		% Value changed function: OKTrainingName
		function OKTrainingNameValueChanged(app, event)
			value = app.OKTrainingName.Value;
			if isa(app.o, 'opticka') && isa(app.o.r,'runExperiment')
				app.o.r.subjectName = value;
			end
		end

		% Value changed function: OKTrainingResearcherName
		function OKTrainingResearcherNameValueChanged(app, event)
			value = app.OKTrainingResearcherName.Value;
			if isa(app.o, 'opticka') && isa(app.o.r,'runExperiment')
				app.o.r.researcherName = value;
			end
		end

		% Value changed function: OKdPPMode
		function OKdPPModeValueChanged(app, event)
			value = app.OKdPPMode.Value;
			if isa(app.o, 'opticka') && isa(app.o.r,'runExperiment')
				app.o.r.dPPMode = value;
			end
		end

		% Value changed function: OKAutopaceSwitch, OKCalEditField, 
		% OKCalibrationfileEditField, OKCalibrationstimulusDropDown, 
		% OKDrawEyeDropDown, OKDurationEditField_Tobii, OKEyeusedDropDown, 
		% OKInitEditField_Tobii, OKManualcalibrationSwitch_Tobii, 
		% OKPaceDurationEditField, OKRadiusEditField_Tobii, 
		% OKSamplerateKnob, OKStrictEditField_Tobii, 
		% OKTrackingmodeDropDown, OKValEditField, OKYEditField_Tobii, 
		% XOKEditField_Tobii
		function OKTobiiSettings(app, event)
			app.tobii.fixation.x = app.XOKEditField_Tobii.Value;
			app.tobii.fixation.y = app.OKYEditField_Tobii.Value;
			app.tobii.fixation.initTime = app.OKInitEditField_Tobii.Value;
			app.tobii.fixation.time = app.OKDurationEditField_Tobii.Value;
			app.tobii.fixation.radius = app.OKRadiusEditField_Tobii.Value;
			app.tobii.fixation.strict = app.OKStrictEditField_Tobii.Value;
			app.tobii.fixation.x = app.XOKEditField_Tobii.Value;
			app.tobii.tracker = app.OKTrackerDropDown.Value;
			app.tobii.trackingMode = app.OKTrackingmodeDropDown.Value;
			app.tobii.eyeUsed = app.OKEyeusedDropDown.Value;
			app.tobii.calStim = app.OKCalibrationstimulusDropDown.Value;
			app.tobii.sampleRate = str2num(app.OKSamplerateKnob.Value);

			app.tobii.calFile = app.OKCalibrationfileEditField.Value;
			app.tobii.calPos = str2num(app.OKCalEditField.Value);
			app.tobii.valPos = str2num(app.OKValEditField.Value);
			if matches(app.OKManualcalibrationSwitch_Tobii.Value,'On')
				app.tobii.manualCalibration = true;
			else
				app.tobii.manualCalibration = false;
			end
			if matches(app.OKAutopaceSwitch.Value,'On')
				app.tobii.autoPace = true;
			else
				app.tobii.autoPace = false;
			end
			if matches(app.OKReloadpreviousSwitch.Value,'On')
				app.tobii.reloadPrevious = true;
			else
				app.tobii.reloadPrevious = false;
			end
			app.tobii.paceDuration = app.OKPaceDurationEditField.Value;
		end

		% Button pushed function: OKeyeCALButton
		function OKeyeCALButtonPushed(app, event)
			[f,p]=uigetfile('*.mat','Please load Tobii Calibration MAT file');
			if isnumeric(f);return;end
			app.CalibrationEditField.Value = [p f];
		end

		% Value changed function: OKTrackerDropDown
		function OKTrackerDropDownValueChanged(app, event)
			value = app.OKTrackerDropDown.Value;
			switch value
				case 'Tobii TX300'
					app.OKTrackingmodeDropDown.Enable = true;
					app.OKEyeusedDropDown.Enable = true;
					app.OKTrackingmodeDropDown.Items = {'Default','Infant','Bright light'};
					app.OKTrackingmodeDropDown.Value = 'Default';
					app.OKSamplerateKnob.Items = {'60', '120','300'};
					app.OKSamplerateKnob.Value = '300';
					app.OKSamplerateKnob.Enable = true;
				case 'Tobii Pro Spectrum'
					app.OKTrackingmodeDropDown.Enable = true;
					app.OKEyeusedDropDown.Enable = true;
					app.OKTrackingmodeDropDown.Items = {'human','macaque'};
					app.OKTrackingmodeDropDown.Value = 'human';
					app.OKSamplerateKnob.Items = {'60', '90','120','150','300','600','1200'};
					app.OKSamplerateKnob.Value = '300';
					app.OKSamplerateKnob.Enable = true;
				case 'Tobii 4C'
					app.OKEyeusedDropDown.Enable = false;
					app.OKTrackingmodeDropDown.Enable = false;
					app.OKSamplerateKnob.Items = {'60','90'};
					app.OKSamplerateKnob.Value = '90';
					app.OKSamplerateKnob.Enable = false;
			end
		end

		% Callback function: OKMenuSaveCopy, OKToolbarSave
		function OKProtocolSaveCopy(app, event)
			if isa(app.o, 'opticka')
				app.o.router('saveProtocol',true);
			end
		end

		% Menu selected function: OKMenuSaveAsData
		function OKSaveData(app, event)
			
		end

		% Image clicked function: Image
		function ImageClicked(app, event)
			web('https://iandol.github.io/opticka/');
		end

		% Value changed function: OKVarList
		function OKVarList_Callback(app, event)
			app.o.editVariable;
			if app.o.r.task.nVars > 0
				set(app.OKDeleteVariable,'Enable','on');
				set(app.OKCopyVariable,'Enable','on');
				set(app.OKEditVariable,'Enable','on');
			elseif app.o.r.task.nVars < 1
				app.OKVarList.Items = {};
				app.OKDeleteVariable.Enable = 'off';
				app.OKCopyVariable.Enable = 'off';
				app.OKEditVariable.Enable = 'off';
			end
		end

		% Value changed function: OKVariableName, OKVariableOffset, 
		% OKVariableStimuli, OKVariableValues
		function OKupdateVariable(app, event)
			app.o.updateVariable();
		end

		% Menu selected function: KeyboardMappingsMenu
		function KeyboardMappingsMenuSelected(app, event)
			if isa(app.o, 'opticka')
				if ismac
					cmd = ['open ' app.o.paths.root filesep 'help' filesep 'Opticka-Keyboard-Mapping.odt'];
					system(cmd);
				elseif isunix
					cmd = ['xdg-open ' app.o.paths.root filesep 'help' filesep 'Opticka-Keyboard-Mapping.odt'];
					system(cmd);
				else
					winopen([app.o.paths.root filesep 'help' filesep 'Opticka-Keyboard-Mapping.odt']);
				end
			end
		end

		% Menu selected function: HomepageMenu
		function HomepageMenuSelected(app, event)
			web('https://cogplatform.github.io/opticka/');
		end

		% Menu selected function: CodeDocumentationMenu
		function CodeDocumentationMenuSelected(app, event)
			web('http://iandol.github.io/OptickaDocs/inherits.html')
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.OKRoot = uifigure('Visible', 'off');
			app.OKRoot.Name = 'Opticka';
			app.OKRoot.Icon = 'opticka.png';
			app.OKRoot.CloseRequestFcn = createCallbackFcn(app, @OKMenuQuit_Callback, true);
			app.OKRoot.Tag = 'OKRoot';
			app.OKMenuFile = uimenu(app.OKRoot);
			app.OKMenuFile.Text = 'File';
			app.OKMenuFile.Tag = 'OKMenuFile';
			app.OKMenuNewProtocol = uimenu(app.OKMenuFile);
			app.OKMenuNewProtocol.MenuSelectedFcn = createCallbackFcn(app, @OKMenuNewProtocol_Callback, true);
			app.OKMenuNewProtocol.Accelerator = 'N';
			app.OKMenuNewProtocol.Text = 'New Protocol';
			app.OKMenuNewProtocol.Tag = 'OKMenuNewProtocol';
			app.OKMenuOpen = uimenu(app.OKMenuFile);
			app.OKMenuOpen.MenuSelectedFcn = createCallbackFcn(app, @OKProtocolOpen, true);
			app.OKMenuOpen.Accelerator = 'O';
			app.OKMenuOpen.Text = 'Open Protocol...';
			app.OKMenuOpen.Tag = 'OKMenuOpen';
			app.OKMenuSaveCopy = uimenu(app.OKMenuFile);
			app.OKMenuSaveCopy.MenuSelectedFcn = createCallbackFcn(app, @OKProtocolSaveCopy, true);
			app.OKMenuSaveCopy.Tooltip = {'Saves the protocol AND copies the state file to a new folder.'};
			app.OKMenuSaveCopy.Separator = 'on';
			app.OKMenuSaveCopy.Accelerator = 'S';
			app.OKMenuSaveCopy.Text = 'Save Protocol Copy...';
			app.OKMenuSaveCopy.Tag = 'OKMenuSave';
			app.OKMenuSave = uimenu(app.OKMenuFile);
			app.OKMenuSave.MenuSelectedFcn = createCallbackFcn(app, @OKProtocolSaveOnly, true);
			app.OKMenuSave.Tooltip = {'Saves the protocol (you must make sure the state file is in the correct folder)'};
			app.OKMenuSave.Text = 'Save Protocol...';
			app.OKMenuSave.Tag = 'OKMenuSave';
			app.OKMenuSaveAsData = uimenu(app.OKMenuFile);
			app.OKMenuSaveAsData.MenuSelectedFcn = createCallbackFcn(app, @OKSaveData, true);
			app.OKMenuSaveAsData.Enable = 'off';
			app.OKMenuSaveAsData.Accelerator = 'P';
			app.OKMenuSaveAsData.Text = 'Save as Data...';
			app.OKMenuSaveAsData.Tag = 'OKMenuSave';
			app.OKMenuStateInfo = uimenu(app.OKMenuFile);
			app.OKMenuStateInfo.MenuSelectedFcn = createCallbackFcn(app, @OKMenuStateInfo_Callback, true);
			app.OKMenuStateInfo.Separator = 'on';
			app.OKMenuStateInfo.Accelerator = 'I';
			app.OKMenuStateInfo.Text = 'Open stateMachine info...';
			app.OKMenuStateInfo.Tag = 'OKMenuStateInfo';
			app.OKMenuQuit = uimenu(app.OKMenuFile);
			app.OKMenuQuit.MenuSelectedFcn = createCallbackFcn(app, @OKMenuQuit_Callback, true);
			app.OKMenuQuit.Separator = 'on';
			app.OKMenuQuit.Accelerator = 'X';
			app.OKMenuQuit.Text = 'Quit Opticka';
			app.OKMenuQuit.Tag = 'OKMenuQuit';
			app.OKMenuEdit = uimenu(app.OKRoot);
			app.OKMenuEdit.Text = 'Edit';
			app.OKMenuEdit.Tag = 'OKMenuEdit';
			app.OKMenuCut = uimenu(app.OKMenuEdit);
			app.OKMenuCut.Enable = 'off';
			app.OKMenuCut.Text = 'Cut';
			app.OKMenuCut.Tag = 'OKMenuCut';
			app.OKMenuCopy = uimenu(app.OKMenuEdit);
			app.OKMenuCopy.Enable = 'off';
			app.OKMenuCopy.Text = 'Copy';
			app.OKMenuCopy.Tag = 'OKMenuCopy';
			app.OKMenuPaste = uimenu(app.OKMenuEdit);
			app.OKMenuPaste.Enable = 'off';
			app.OKMenuPaste.Text = 'Paste';
			app.OKMenuPaste.Tag = 'OKMenuPaste';
			app.OKMenuTools = uimenu(app.OKRoot);
			app.OKMenuTools.Text = 'Stimulus';
			app.OKMenuTools.Tag = 'OKMenuTools';
			app.OKMenuGrating = uimenu(app.OKMenuTools);
			app.OKMenuGrating.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuGrating.Tooltip = {'gratingStimulus'};
			app.OKMenuGrating.Accelerator = 'G';
			app.OKMenuGrating.Text = 'Grating';
			app.OKMenuGrating.Tag = 'OKMenuGrating';
			app.OKMenuColourGrating = uimenu(app.OKMenuTools);
			app.OKMenuColourGrating.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuColourGrating.Tooltip = {'colourGratingStimulus'};
			app.OKMenuColourGrating.Accelerator = 'C';
			app.OKMenuColourGrating.Text = 'Colour Grating';
			app.OKMenuColourGrating.Tag = 'OKMenuGrating';
			app.OKMenuGabor = uimenu(app.OKMenuTools);
			app.OKMenuGabor.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuGabor.Tooltip = {'gaborStimulus'};
			app.OKMenuGabor.Accelerator = 'R';
			app.OKMenuGabor.Text = 'Gabor';
			app.OKMenuGabor.Tag = 'OKMenuGabor';
			app.OKMenuLogGabor = uimenu(app.OKMenuTools);
			app.OKMenuLogGabor.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuLogGabor.Tooltip = {'logGaborStimulus'};
			app.OKMenuLogGabor.Accelerator = 'L';
			app.OKMenuLogGabor.Text = 'Oriented Noise Gabor';
			app.OKMenuLogGabor.Tag = 'OKMenuGabor';
			app.OKMenuBar = uimenu(app.OKMenuTools);
			app.OKMenuBar.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuBar.Tooltip = {'barStimulus'};
			app.OKMenuBar.Accelerator = 'B';
			app.OKMenuBar.Text = 'Bar';
			app.OKMenuBar.Tag = 'OKMenuBar';
			app.OKMenuDots = uimenu(app.OKMenuTools);
			app.OKMenuDots.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuDots.Tooltip = {'dotsStimulus'};
			app.OKMenuDots.Accelerator = 'D';
			app.OKMenuDots.Text = 'Coherent Dots';
			app.OKMenuDots.Tag = 'OKMenuDots';
			app.OKMenuNDots = uimenu(app.OKMenuTools);
			app.OKMenuNDots.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuNDots.Tooltip = {'nDotsStimulus'};
			app.OKMenuNDots.Accelerator = 'Q';
			app.OKMenuNDots.Text = 'Newsome Dots';
			app.OKMenuNDots.Tag = 'OKMenuNDots';
			app.OKMenuPicture = uimenu(app.OKMenuTools);
			app.OKMenuPicture.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuPicture.Tooltip = {'imageStimulus'};
			app.OKMenuPicture.Accelerator = 'T';
			app.OKMenuPicture.Text = 'Picture';
			app.OKMenuPicture.Tag = 'OKMenuTexture';
			app.OKMenuMovie = uimenu(app.OKMenuTools);
			app.OKMenuMovie.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuMovie.Tooltip = {'movieStimulus'};
			app.OKMenuMovie.Accelerator = 'M';
			app.OKMenuMovie.Text = 'Movie';
			app.OKMenuMovie.Tag = 'OKMenuTexture';
			app.OKMenuDisc = uimenu(app.OKMenuTools);
			app.OKMenuDisc.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuDisc.Tooltip = {'discStimulus'; ''};
			app.OKMenuDisc.Accelerator = 'K';
			app.OKMenuDisc.Text = 'Smoothed Disc';
			app.OKMenuDisc.Tag = 'OKMenuSpot';
			app.OKMenuSpot = uimenu(app.OKMenuTools);
			app.OKMenuSpot.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuSpot.Tooltip = {'spotStimulus'};
			app.OKMenuSpot.Accelerator = 'V';
			app.OKMenuSpot.Text = 'Spot';
			app.OKMenuSpot.Tag = 'OKMenuSpot';
			app.OKMenuFixationCross = uimenu(app.OKMenuTools);
			app.OKMenuFixationCross.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuFixationCross.Tooltip = {'fixationCrossStimulus'};
			app.OKMenuFixationCross.Accelerator = 'F';
			app.OKMenuFixationCross.Text = 'Fixation Cross';
			app.OKMenuFixationCross.Tag = 'OKMenuSpot';
			app.OKMenuTargetInducer = uimenu(app.OKMenuTools);
			app.OKMenuTargetInducer.MenuSelectedFcn = createCallbackFcn(app, @OKAddStimulus_Panel, true);
			app.OKMenuTargetInducer.Separator = 'on';
			app.OKMenuTargetInducer.Text = 'Target Inducer';
			app.OKMenuTargetInducer.Tag = 'OKMenuTargetInducer';
			app.OKMenuPlaid = uimenu(app.OKMenuTools);
			app.OKMenuPlaid.Enable = 'off';
			app.OKMenuPlaid.Separator = 'on';
			app.OKMenuPlaid.Text = 'Plaid';
			app.OKMenuPlaid.Tag = 'OKMenuPlaid';
			app.OKMenuNoiseTexture = uimenu(app.OKMenuTools);
			app.OKMenuNoiseTexture.Enable = 'off';
			app.OKMenuNoiseTexture.Text = 'Noise Texture';
			app.OKMenuNoiseTexture.Tag = 'OKMenuNoiseTexture';
			app.OKMenuLineTexture = uimenu(app.OKMenuTools);
			app.OKMenuLineTexture.Enable = 'off';
			app.OKMenuLineTexture.Text = 'Line Texture';
			app.OKMenuLineTexture.Tag = 'OKMenuLineTexture';
			app.OKReverseCorrelation = uimenu(app.OKMenuTools);
			app.OKReverseCorrelation.Enable = 'off';
			app.OKReverseCorrelation.Text = 'Reverse Correlation';
			app.OKReverseCorrelation.Tag = 'Untitled_17';
			app.RunMenu = uimenu(app.OKRoot);
			app.RunMenu.Text = 'Run';
			app.OKRunTrainingSession = uimenu(app.RunMenu);
			app.OKRunTrainingSession.MenuSelectedFcn = createCallbackFcn(app, @OKStartTask_Callback, true);
			app.OKRunTrainingSession.Accelerator = '1';
			app.OKRunTrainingSession.Text = 'Run Behavioural Task';
			app.OKRunTrainingSession.Tag = 'OKRunTrainingSession';
			app.OKRunExperiment = uimenu(app.RunMenu);
			app.OKRunExperiment.MenuSelectedFcn = createCallbackFcn(app, @OKStartMOC_Callback, true);
			app.OKRunExperiment.Accelerator = '2';
			app.OKRunExperiment.Text = 'Run MOC Task';
			app.OKRunExperiment.Tag = 'OKRunExperiment';
			app.OKRunRFMapper = uimenu(app.RunMenu);
			app.OKRunRFMapper.Separator = 'on';
			app.OKRunRFMapper.Accelerator = '3';
			app.OKRunRFMapper.Text = 'Run RF Mapper';
			app.OKRunRFMapper.Tag = 'OKRunRFMapper';
			app.OKMenuIO = uimenu(app.OKRoot);
			app.OKMenuIO.Text = 'Input/Output';
			app.OKMenuIO.Tag = 'OKMenuIO';
			app.OKuseEyelink = uimenu(app.OKMenuIO);
			app.OKuseEyelink.MenuSelectedFcn = createCallbackFcn(app, @OKuseEyelink_Callback, true);
			app.OKuseEyelink.Checked = 'on';
			app.OKuseEyelink.Text = 'Eyelink Eyetracker';
			app.OKuseEyelink.Tag = 'OKuseEyeLink';
			app.OKuseTobii = uimenu(app.OKMenuIO);
			app.OKuseTobii.MenuSelectedFcn = createCallbackFcn(app, @OKuseTobiiMenuSelected, true);
			app.OKuseTobii.Text = 'Tobii Eyetracker';
			app.OKuseLabJackTStrobe = uimenu(app.OKMenuIO);
			app.OKuseLabJackTStrobe.MenuSelectedFcn = createCallbackFcn(app, @OKuseLabJackTStrobeMenuSelected, true);
			app.OKuseLabJackTStrobe.Separator = 'on';
			app.OKuseLabJackTStrobe.Text = 'LabJack T-series Strobe';
			app.OKuseDisplayPP = uimenu(app.OKMenuIO);
			app.OKuseDisplayPP.MenuSelectedFcn = createCallbackFcn(app, @OKuseDisplayPPMenuSelected, true);
			app.OKuseDisplayPP.Text = 'Display++ Strobe';
			app.OKuseDataPixx = uimenu(app.OKMenuIO);
			app.OKuseDataPixx.MenuSelectedFcn = createCallbackFcn(app, @OKuseDataPixx_Callback, true);
			app.OKuseDataPixx.Text = 'DataPixx Strobe';
			app.OKuseDataPixx.Tag = 'OKuseDataPixx';
			app.OKuseLabJackStrobe = uimenu(app.OKMenuIO);
			app.OKuseLabJackStrobe.MenuSelectedFcn = createCallbackFcn(app, @OKuseLabJackStrobeMenuSelected, true);
			app.OKuseLabJackStrobe.Text = 'LabJack U-series Strobe';
			app.OKuseArduino = uimenu(app.OKMenuIO);
			app.OKuseArduino.MenuSelectedFcn = createCallbackFcn(app, @OKuseArduinoMenuSelected, true);
			app.OKuseArduino.Separator = 'on';
			app.OKuseArduino.Text = 'Arduino Reward';
			app.OKuseLabJackReward = uimenu(app.OKMenuIO);
			app.OKuseLabJackReward.MenuSelectedFcn = createCallbackFcn(app, @OKuseLabJackRewardMenuSelected, true);
			app.OKuseLabJackReward.Text = 'LabJack U-series Reward';
			app.OKuseEyeOccluder = uimenu(app.OKMenuIO);
			app.OKuseEyeOccluder.MenuSelectedFcn = createCallbackFcn(app, @OKuseEyeOccluder_Callback, true);
			app.OKuseEyeOccluder.Enable = 'off';
			app.OKuseEyeOccluder.Separator = 'on';
			app.OKuseEyeOccluder.Checked = 'on';
			app.OKuseEyeOccluder.Text = 'Eye Occluder?';
			app.OKuseEyeOccluder.Tag = 'OKuseLabJack';
			app.OKuseMagStim = uimenu(app.OKMenuIO);
			app.OKuseMagStim.Enable = 'off';
			app.OKuseMagStim.Separator = 'on';
			app.OKuseMagStim.Checked = 'on';
			app.OKuseMagStim.Text = 'Mag Stim?';
			app.OKuseMagStim.Tag = 'OKuseLabJack';
			app.OKMenuPreferences = uimenu(app.OKRoot);
			app.OKMenuPreferences.Text = 'Calibration';
			app.OKMenuPreferences.Tag = 'OKMenuPreferences';
			app.OKMenuCalibrateLuminance = uimenu(app.OKMenuPreferences);
			app.OKMenuCalibrateLuminance.MenuSelectedFcn = createCallbackFcn(app, @OKMenuCalibrateLuminance_Callback, true);
			app.OKMenuCalibrateLuminance.Separator = 'on';
			app.OKMenuCalibrateLuminance.Text = 'Calibrate Gamma...';
			app.OKMenuCalibrateLuminance.Tag = 'OKMenuCalibrateLuminance';
			app.OKMenuCalibrateSize = uimenu(app.OKMenuPreferences);
			app.OKMenuCalibrateSize.MenuSelectedFcn = createCallbackFcn(app, @OKMenuCalibrateSize_Callback, true);
			app.OKMenuCalibrateSize.Text = 'Calibrate Size...';
			app.OKMenuCalibrateSize.Tag = 'OKMenuCalibrateSize';
			app.OKMenuLoadGamma = uimenu(app.OKMenuPreferences);
			app.OKMenuLoadGamma.MenuSelectedFcn = createCallbackFcn(app, @OKMenuLoadGamma_Callback, true);
			app.OKMenuLoadGamma.Separator = 'on';
			app.OKMenuLoadGamma.Text = 'Load Gamma...';
			app.OKMenuLoadGamma.Tag = 'OKMenuLoadGamma';
			app.OKMenuSaveGamma = uimenu(app.OKMenuPreferences);
			app.OKMenuSaveGamma.MenuSelectedFcn = createCallbackFcn(app, @OKMenuSaveGamma_Callback, true);
			app.OKMenuSaveGamma.Text = 'Save Gamma...';
			app.OKMenuSaveGamma.Tag = 'OKMenuSaveGamma';
			app.OKMenumanageCode = uimenu(app.OKMenuPreferences);
			app.OKMenumanageCode.MenuSelectedFcn = createCallbackFcn(app, @OKMenumanageCode_Callback, true);
			app.OKMenumanageCode.Enable = 'off';
			app.OKMenumanageCode.Separator = 'on';
			app.OKMenumanageCode.Text = 'Check for Updates';
			app.OKMenumanageCode.Tag = 'OKMenumanageCode';
			app.OKMenuEditConfiguration = uimenu(app.OKMenuPreferences);
			app.OKMenuEditConfiguration.Enable = 'off';
			app.OKMenuEditConfiguration.Separator = 'on';
			app.OKMenuEditConfiguration.Text = 'Edit Configuration';
			app.OKMenuEditConfiguration.Tag = 'OKMenuEditConfiguration';
			app.OKMenuLogs = uimenu(app.OKRoot);
			app.OKMenuLogs.Text = 'Experiment Logs';
			app.OKMenuLogs.Tag = 'OKMenuLogs';
			app.OKMenuTimingLog = uimenu(app.OKMenuLogs);
			app.OKMenuTimingLog.MenuSelectedFcn = createCallbackFcn(app, @OKMenuTimingLog_Callback, true);
			app.OKMenuTimingLog.Text = 'Timing Log';
			app.OKMenuTimingLog.Tag = 'OKMenuAllTimingLogs';
			app.OKMenuTaskLog = uimenu(app.OKMenuLogs);
			app.OKMenuTaskLog.MenuSelectedFcn = createCallbackFcn(app, @OKMenuTaskLog_Callback, true);
			app.OKMenuTaskLog.Text = 'Task Sequence Log';
			app.OKMenuTaskLog.Tag = 'OKMenuStimulusLog';
			app.OKMenuBehaviourLog = uimenu(app.OKMenuLogs);
			app.OKMenuBehaviourLog.MenuSelectedFcn = createCallbackFcn(app, @OKMenuBehaviourLogSelected, true);
			app.OKMenuBehaviourLog.Text = 'Behaviour Log';
			app.OKMenuBehaviourLog.Tag = 'OKMenuStimulusLog';
			app.OKMenuStateLog = uimenu(app.OKMenuLogs);
			app.OKMenuStateLog.MenuSelectedFcn = createCallbackFcn(app, @OKMenuStateLogSelected, true);
			app.OKMenuStateLog.Text = 'State Machine Log';
			app.OKMenuStateLog.Tag = 'OKMenuStimulusLog';
			app.OKMenuShowGammaPlots = uimenu(app.OKMenuLogs);
			app.OKMenuShowGammaPlots.MenuSelectedFcn = createCallbackFcn(app, @OKMenuShowGammaPlots_Callback, true);
			app.OKMenuShowGammaPlots.Separator = 'on';
			app.OKMenuShowGammaPlots.Text = 'Gamma Correction Plot';
			app.OKMenuShowGammaPlots.Tag = 'OKMenuShowGammaPlots';
			app.OKMenurfMapperLog = uimenu(app.OKMenuLogs);
			app.OKMenurfMapperLog.Enable = 'off';
			app.OKMenurfMapperLog.Separator = 'on';
			app.OKMenurfMapperLog.Text = 'RF Mapper Log';
			app.OKMenurfMapperLog.Tag = 'OKMenurfMapperLog';
			app.HelpMenu = uimenu(app.OKRoot);
			app.HelpMenu.Text = 'Help';
			app.HomepageMenu = uimenu(app.HelpMenu);
			app.HomepageMenu.MenuSelectedFcn = createCallbackFcn(app, @HomepageMenuSelected, true);
			app.HomepageMenu.Text = 'Home page';
			app.CodeDocumentationMenu = uimenu(app.HelpMenu);
			app.CodeDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @CodeDocumentationMenuSelected, true);
			app.CodeDocumentationMenu.Text = 'Code Documentation';
			app.KeyboardMappingsMenu = uimenu(app.HelpMenu);
			app.KeyboardMappingsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyboardMappingsMenuSelected, true);
			app.KeyboardMappingsMenu.Separator = 'on';
			app.KeyboardMappingsMenu.Text = 'Keyboard Mappings';
			app.OKToolbar = uitoolbar(app.OKRoot);
			app.OKToolbar.Tag = 'OKToolbar';
			app.OKToolbarInitialise = uipushtool(app.OKToolbar);
			app.OKToolbarInitialise.Tag = 'OKToolbarInitialise';
			app.OKToolbarInitialise.Tooltip = 'Initialise Opticka with a fresh set of settings';
			app.OKToolbarInitialise.ClickedCallback = createCallbackFcn(app, @OKToolbarInitialise_ClickedCallback, true);
			app.OKToolbarInitialise.Icon = 'document.svg';
			app.OKToolbarOpen = uipushtool(app.OKToolbar);
			app.OKToolbarOpen.Tag = 'OKToolbarOpen';
			app.OKToolbarOpen.Tooltip = {'Open Protocol'};
			app.OKToolbarOpen.ClickedCallback = createCallbackFcn(app, @OKProtocolOpen, true);
			app.OKToolbarOpen.Icon = 'folder-outline-add.svg';
			app.OKToolbarSave = uipushtool(app.OKToolbar);
			app.OKToolbarSave.Tag = 'OKToolbarSave';
			app.OKToolbarSave.Tooltip = {'Save protocol copy'};
			app.OKToolbarSave.ClickedCallback = createCallbackFcn(app, @OKProtocolSaveCopy, true);
			app.OKToolbarSave.Icon = 'save-disk.svg';
			app.OKToolbarDefinePath = uipushtool(app.OKToolbar);
			app.OKToolbarDefinePath.Tag = 'OKToolbarDefinePath';
			app.OKToolbarDefinePath.Tooltip = 'Choose a folder to save eyelink and other log files';
			app.OKToolbarDefinePath.ClickedCallback = createCallbackFcn(app, @OKToolbarDefinePath_ClickedCallback, true);
			app.OKToolbarDefinePath.Enable = 'off';
			app.OKToolbarDefinePath.Icon = 'arrow-outline-down.svg';
			app.OKToolbarDefinePath.Separator = 'on';
			app.OKToggleUI = uipushtool(app.OKToolbar);
			app.OKToggleUI.Tag = 'OKToggleUI';
			app.OKToggleUI.Tooltip = 'Toggle settings panels...';
			app.OKToggleUI.ClickedCallback = createCallbackFcn(app, @OKToggleUI_ClickedCallback, true);
			app.OKToggleUI.Icon = 'cog.svg';
			app.OKToggleUI.Separator = 'on';
			app.OKStartRFMapper = uipushtool(app.OKToolbar);
			app.OKStartRFMapper.Tag = 'OKRFMapper';
			app.OKStartRFMapper.Tooltip = {'Start the RF Mapper. If you need eyetracker control, make sure to select eyelink in menu.'};
			app.OKStartRFMapper.ClickedCallback = createCallbackFcn(app, @OKStartRFMapper_Callback, true);
			app.OKStartRFMapper.Icon = 'map.svg';
			app.OKStartRFMapper.Separator = 'on';
			app.OKStartTask = uipushtool(app.OKToolbar);
			app.OKStartTask.Tag = 'OKStartTask';
			app.OKStartTask.Tooltip = 'Start a behavioural task...';
			app.OKStartTask.ClickedCallback = createCallbackFcn(app, @OKStartTask_Callback, true);
			app.OKStartTask.Icon = 'play-outline.svg';
			app.OKStartTask.Separator = 'on';
			app.OKStartMOC = uipushtool(app.OKToolbar);
			app.OKStartMOC.Tag = 'OKToolbarRun';
			app.OKStartMOC.Tooltip = 'Start a MOC Task';
			app.OKStartMOC.ClickedCallback = createCallbackFcn(app, @OKStartMOC_Callback, true);
			app.OKStartMOC.Icon = 'play.svg';
			app.OKToolbarStop = uipushtool(app.OKToolbar);
			app.OKToolbarStop.Tag = 'OKToolbarStop';
			app.OKToolbarStop.Tooltip = 'STOP';
			app.OKToolbarStop.ClickedCallback = createCallbackFcn(app, @OKToolbarStop_ClickedCallback, true);
			app.OKToolbarStop.Enable = 'off';
			app.OKToolbarStop.Icon = 'block.svg';
			app.OKToolbarAbort = uipushtool(app.OKToolbar);
			app.OKToolbarAbort.Tag = 'OKToolbarAbort';
			app.OKToolbarAbort.Tooltip = 'ABORT';
			app.OKToolbarAbort.ClickedCallback = createCallbackFcn(app, @OKToolbarAbort_ClickedCallback, true);
			app.OKToolbarAbort.Enable = 'off';
			app.OKToolbarAbort.Icon = 'close-outline.svg';
			app.OKPanelTellOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelTellOmniplex.Tag = 'OKPanelTellOmniplex';
			app.OKPanelTellOmniplex.Tooltip = 'Connect to Omniplex!';
			app.OKPanelTellOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelTellOmniplex_ClickedCallback, true);
			app.OKPanelTellOmniplex.Enable = 'off';
			app.OKPanelTellOmniplex.Icon = 'chat-bubble-dots.svg';
			app.OKPanelTellOmniplex.Separator = 'on';
			app.OKPanelReconnectOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelReconnectOmniplex.Tag = 'OKPanelReconnectOmniplex';
			app.OKPanelReconnectOmniplex.Tooltip = 'Reconnect to Omniplex!';
			app.OKPanelReconnectOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelReconnectOmniplex_ClickedCallback, true);
			app.OKPanelReconnectOmniplex.Enable = 'off';
			app.OKPanelReconnectOmniplex.Icon = 'reload.svg';
			app.OKPanelSendOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelSendOmniplex.Tag = 'OKPanelSendOmniplex';
			app.OKPanelSendOmniplex.Tooltip = 'Send Omniplex Stimulus';
			app.OKPanelSendOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelSendOmniplex_ClickedCallback, true);
			app.OKPanelSendOmniplex.Enable = 'off';
			app.OKPanelSendOmniplex.Icon = 'arrow-thick-right.svg';
			app.OKPanelPingOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelPingOmniplex.Tag = 'OKPanelPingOmniplex';
			app.OKPanelPingOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelPingOmniplex_ClickedCallback, true);
			app.OKPanelPingOmniplex.Enable = 'off';
			app.OKPanelPingOmniplex.Icon = 'stethoscope.svg';
			app.OKPanelDisconnectOmniplex = uipushtool(app.OKToolbar);
			app.OKPanelDisconnectOmniplex.Tag = 'OKPanelDisconnectOmniplex';
			app.OKPanelDisconnectOmniplex.ClickedCallback = createCallbackFcn(app, @OKPanelDisconnectOmniplex_ClickedCallback, true);
			app.OKPanelDisconnectOmniplex.Enable = 'off';
			app.OKPanelDisconnectOmniplex.Icon = 'stand-by.svg';
			app.OKToolbarToggleRemote = uitoggletool(app.OKToolbar);
			app.OKToolbarToggleRemote.Tag = 'OKToolbarToggleRemote';
			app.OKToolbarToggleRemote.Tooltip = 'Toggle into remote client mode';
			app.OKToolbarToggleRemote.Enable = 'off';
			app.OKToolbarToggleRemote.Icon = 'station.svg';
			app.OKToolbarToggleRemote.Separator = 'on';
			app.OKToolbarToggleRemote.OnCallback = createCallbackFcn(app, @OKToolbarToggleRemote_OnCallback, true);
			app.OKPanelIndividual = uibuttongroup(app.OKRoot);
			app.OKPanelIndividual.Title = 'Individual Options';
			app.OKPanelIndividual.Tag = 'OKPanelIndividual';
			app.OKPanelStimulus = uipanel(app.OKPanelIndividual);
			app.OKPanelStimulus.Tag = 'OKPanelStimulus';
			app.OKPanelStimulusText = uilabel(app.OKPanelStimulus);
			app.OKPanelStimulusText.Tag = 'OKPanelStimulusText';
			app.OKPanelStimulusText.Enable = 'off';
			app.OKPanelStimulusText.Text = 'Select a stimulus to show options here...';
			app.OKOmniplexStatus = uilabel(app.OKPanelIndividual);
			app.OKOmniplexStatus.Tag = 'OKOmniplexStatus';
			app.OKOmniplexStatus.Tooltip = 'This connection status is for the online display ONLY, it does not affect data collection communication via the datapixx';
			app.OKOmniplexStatus.Text = 'Remote Analysis Server: disconnected';
			app.OKPanelStimulusList = uibuttongroup(app.OKPanelIndividual);
			app.OKPanelStimulusList.Title = 'Stimulus List <metaStimulus>';
			app.OKPanelStimulusList.Tag = 'OKPanelStimulusList';
			app.OKAddStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKAddStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKAddStimulus_Callback, true);
			app.OKAddStimulus.Tag = 'OKAddStimulus';
			app.OKAddStimulus.Enable = 'off';
			app.OKAddStimulus.Text = 'Add';
			app.OKDeleteStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKDeleteStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKDeleteStimulus_Callback, true);
			app.OKDeleteStimulus.Tag = 'OKDeleteStimulus';
			app.OKDeleteStimulus.Enable = 'off';
			app.OKDeleteStimulus.Text = 'Delete';
			app.OKCopyStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKCopyStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKCopyStimulus_Callback, true);
			app.OKCopyStimulus.Tag = 'OKCopyStimulus';
			app.OKCopyStimulus.Enable = 'off';
			app.OKCopyStimulus.Text = 'Copy';
			app.OKInspectStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKInspectStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKInspectStimulus_Callback, true);
			app.OKInspectStimulus.Tag = 'OKInspectStimulus';
			app.OKInspectStimulus.Tooltip = {'Help for stimuli'};
			app.OKInspectStimulus.Text = '?';
			app.OKModifyStimulus = uibutton(app.OKPanelStimulusList, 'push');
			app.OKModifyStimulus.ButtonPushedFcn = createCallbackFcn(app, @OKModifyStimulus_Callback, true);
			app.OKModifyStimulus.Tag = 'OKModifyStimulus';
			app.OKModifyStimulus.Enable = 'off';
			app.OKModifyStimulus.Text = 'Modify';
			app.OKStimulusDown = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusDown.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusDown_Callback, true);
			app.OKStimulusDown.Tag = 'OKStimulusDown';
			app.OKStimulusDown.Enable = 'off';
			app.OKStimulusDown.Tooltip = {'Move stimulus DOWN'};
			app.OKStimulusDown.Text = '⇩';
			app.OKStimulusUp = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusUp.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusUp_Callback, true);
			app.OKStimulusUp.Tag = 'OKStimulusUp';
			app.OKStimulusUp.Enable = 'off';
			app.OKStimulusUp.Tooltip = {'Move stimulus UP'};
			app.OKStimulusUp.Text = '⇧';
			app.OKStimulusRun = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRun.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRun_Callback, true);
			app.OKStimulusRun.Tag = 'OKStimulusRun';
			app.OKStimulusRun.Enable = 'off';
			app.OKStimulusRun.Tooltip = 'Run the selected stimulus in a window';
			app.OKStimulusRun.Text = '⊳';
			app.OKStimulusRunBenchmark = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunBenchmark.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunBenchmark_Callback, true);
			app.OKStimulusRunBenchmark.Tag = 'OKStimulusRunBenchmark';
			app.OKStimulusRunBenchmark.Enable = 'off';
			app.OKStimulusRunBenchmark.Tooltip = 'Run the selected stimulus and benchmark maximum FPS';
			app.OKStimulusRunBenchmark.Text = '※⊳';
			app.OKStimulusRunAll = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunAll.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunAll_Callback, true);
			app.OKStimulusRunAll.Tag = 'OKStimulusRunAll';
			app.OKStimulusRunAll.Enable = 'off';
			app.OKStimulusRunAll.Tooltip = 'Preview All Stimuli...';
			app.OKStimulusRunAll.Text = '►';
			app.OKStimulusRunAllBenchmark = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunAllBenchmark.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunAllBenchmark_Callback, true);
			app.OKStimulusRunAllBenchmark.Tag = 'OKStimulusRunAllBenchmark';
			app.OKStimulusRunAllBenchmark.Enable = 'off';
			app.OKStimulusRunAllBenchmark.Tooltip = 'Benchmark All Stimuli...';
			app.OKStimulusRunAllBenchmark.Text = '※►';
			app.OKStimList = uilistbox(app.OKPanelStimulusList);
			app.OKStimList.Items = {};
			app.OKStimList.ValueChangedFcn = createCallbackFcn(app, @OKStimList_Callback, true);
			app.OKStimList.Tag = 'OKStimList';
			app.OKStimList.Value = {};
			app.OKStimulusRunSingle = uibutton(app.OKPanelStimulusList, 'push');
			app.OKStimulusRunSingle.ButtonPushedFcn = createCallbackFcn(app, @OKStimulusRunSingle_Callback, true);
			app.OKStimulusRunSingle.Tag = 'OKStimulusRunSingle';
			app.OKStimulusRunSingle.Enable = 'off';
			app.OKStimulusRunSingle.Tooltip = 'Single run using eyetracker';
			app.OKStimulusRunSingle.Text = '↻';
			app.OKPanelVariables = uipanel(app.OKPanelIndividual);
			app.OKPanelVariables.Title = 'Independent Variables';
			app.OKPanelVariables.Tag = 'OKPanelVariables';
			app.text140 = uilabel(app.OKPanelVariables);
			app.text140.Tag = 'text140';
			app.text140.Text = 'Affects Stimuli';
			app.text141 = uilabel(app.OKPanelVariables);
			app.text141.Tag = 'text141';
			app.text141.Text = 'Variable Values';
			app.text142 = uilabel(app.OKPanelVariables);
			app.text142.Tag = 'text142';
			app.text142.Text = 'Variable Name';
			app.OKVariablesLinear = uibutton(app.OKPanelVariables, 'push');
			app.OKVariablesLinear.ButtonPushedFcn = createCallbackFcn(app, @OKVariablesLinear_Callback, true);
			app.OKVariablesLinear.Tag = 'OKVariablesLinear';
			app.OKVariablesLinear.Text = 'lin';
			app.OKVariablesLog = uibutton(app.OKPanelVariables, 'push');
			app.OKVariablesLog.ButtonPushedFcn = createCallbackFcn(app, @OKVariablesLog_Callback, true);
			app.OKVariablesLog.Tag = 'OKVariablesLog';
			app.OKVariablesLog.Text = 'log';
			app.OKCopyVariableNameValues = uibutton(app.OKPanelVariables, 'push');
			app.OKCopyVariableNameValues.ButtonPushedFcn = createCallbackFcn(app, @OKCopyVariableNameValues_Callback, true);
			app.OKCopyVariableNameValues.Tag = 'OKCopyVariableNameValues';
			app.OKCopyVariableNameValues.Text = '⇦';
			app.text178 = uilabel(app.OKPanelVariables);
			app.text178.Tag = 'text178';
			app.text178.Text = 'Stimulus; Modifier';
			app.OKVariableStimuli = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableStimuli.ValueChangedFcn = createCallbackFcn(app, @OKupdateVariable, true);
			app.OKVariableStimuli.Tag = 'OKVariableStimuli';
			app.OKVariableStimuli.Value = '1';
			app.OKVariableValues = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableValues.ValueChangedFcn = createCallbackFcn(app, @OKupdateVariable, true);
			app.OKVariableValues.Tag = 'OKVariableValues';
			app.OKVariableValues.Value = '[-90:45:90]';
			app.OKVariableName = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableName.ValueChangedFcn = createCallbackFcn(app, @OKupdateVariable, true);
			app.OKVariableName.Tag = 'OKVariableName';
			app.OKVariableName.Tooltip = {'NOTE: this can be any propery of a stimulus, not just those in the list...'};
			app.OKVariableName.Value = 'angle';
			app.OKVariableOffset = uieditfield(app.OKPanelVariables, 'text');
			app.OKVariableOffset.ValueChangedFcn = createCallbackFcn(app, @OKupdateVariable, true);
			app.OKVariableOffset.Tag = 'OKVariableOffset';
			app.OKVariableOffset.Tooltip = {'This allows you to provide a modifier to other stimuli. For example:  "2; ''invert''"  -- will take the current value and invert it and apply it to stimulus 2. Other modifiers are xoffset yoffset xvar yvar'};
			app.OKVariableOffset.Value = '[]';
			app.OKVariableList = uilistbox(app.OKPanelVariables);
			app.OKVariableList.Items = {'xyPosition', 'xPosition', 'yPosition', 'angle', 'motionAngle', 'size', 'sf', 'tf', 'contrast', 'alpha', 'speed', 'phase', 'startPosition', 'barLength', 'barWidth', 'alpha', 'nDots', 'coherence', 'scale', 'driftDirection'};
			app.OKVariableList.Tag = 'OKVariableList';
			app.OKVariableList.Tooltip = {'Variable names. NOTE: you can use ANY parameter of a stimulus, not just those in this list...'};
			app.OKVariableList.Value = 'angle';
			app.OKPanelVarList = uibuttongroup(app.OKPanelIndividual);
			app.OKPanelVarList.Title = 'Ind. Variable List <taskSequence>';
			app.OKPanelVarList.Tag = 'uipanel44';
			app.OKAddVariable = uibutton(app.OKPanelVarList, 'push');
			app.OKAddVariable.ButtonPushedFcn = createCallbackFcn(app, @OKAddVariable_Callback, true);
			app.OKAddVariable.Tag = 'OKAddVariable';
			app.OKAddVariable.Text = 'Add';
			app.OKDeleteVariable = uibutton(app.OKPanelVarList, 'push');
			app.OKDeleteVariable.ButtonPushedFcn = createCallbackFcn(app, @OKDeleteVariable_Callback, true);
			app.OKDeleteVariable.Tag = 'OKDeleteVariable';
			app.OKDeleteVariable.Enable = 'off';
			app.OKDeleteVariable.Text = 'Delete';
			app.OKCopyVariable = uibutton(app.OKPanelVarList, 'push');
			app.OKCopyVariable.ButtonPushedFcn = createCallbackFcn(app, @OKCopyVariable_Callback, true);
			app.OKCopyVariable.Tag = 'OKCopyVariable';
			app.OKCopyVariable.Enable = 'off';
			app.OKCopyVariable.Text = 'Copy';
			app.OKEditVariable = uibutton(app.OKPanelVarList, 'push');
			app.OKEditVariable.ButtonPushedFcn = createCallbackFcn(app, @OKEditVariable_Callback, true);
			app.OKEditVariable.Tag = 'OKEditVariable';
			app.OKEditVariable.Enable = 'off';
			app.OKEditVariable.Text = 'Edit';
			app.OKInspectVariable = uibutton(app.OKPanelVarList, 'push');
			app.OKInspectVariable.ButtonPushedFcn = createCallbackFcn(app, @OKInspectVariable_Callback, true);
			app.OKInspectVariable.Tag = 'OKInspectVariable';
			app.OKInspectVariable.Tooltip = {'Help for variables'};
			app.OKInspectVariable.Text = '?';
			app.OKVarList = uilistbox(app.OKPanelVarList);
			app.OKVarList.Items = {};
			app.OKVarList.ValueChangedFcn = createCallbackFcn(app, @OKVarList_Callback, true);
			app.OKVarList.Tag = 'OKVarList';
			app.OKVarList.Value = {};
			app.OKOptickaVersion = uilabel(app.OKPanelIndividual);
			app.OKOptickaVersion.Tag = 'OKOptickaVersion';
			app.OKOptickaVersion.Text = 'Loading...';
			app.TabGroup = uitabgroup(app.OKRoot);
			app.TabGroup.Tag = 'OKTabs';
			app.DisplayTab = uitab(app.TabGroup);
			app.DisplayTab.Title = 'Display';
			app.GridLayout = uigridlayout(app.DisplayTab);
			app.text8 = uilabel(app.GridLayout);
			app.text8.Tag = 'text8';
			app.text8.Text = 'Background Colour';
			app.OKbackgroundColour = uieditfield(app.GridLayout, 'text');
			app.OKbackgroundColour.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKbackgroundColour.Tag = 'OKbackgroundColour';
			app.OKbackgroundColour.Value = '0.5 0.5 0.5';
			app.text203 = uilabel(app.GridLayout);
			app.text203.Tag = 'text203';
			app.text203.Text = 'PTB Verbosity';
			app.OKbenchmark = uicheckbox(app.GridLayout);
			app.OKbenchmark.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKbenchmark.Tag = 'OKbenchmark';
			app.OKbenchmark.Tooltip = 'Flip as fast as possible to test maximum framerate';
			app.OKbenchmark.Text = 'Benchmark?';
			app.OKverbosityLevel = uieditfield(app.GridLayout, 'text');
			app.OKverbosityLevel.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKverbosityLevel.Tag = 'OKverbosityLevel';
			app.OKverbosityLevel.Tooltip = 'Level of verbosity logging used by PTB';
			app.OKverbosityLevel.Value = '4';
			app.OKbitDepth = uidropdown(app.GridLayout);
			app.OKbitDepth.Items = {'FloatingPoint32BitIfPossible', '8bit', 'Native10bit', 'Native11bit', 'Native16bit', 'PseudoGray', 'HDR', 'Bits++Color++Output', 'Bits++Mono++Output', 'Bits++Bits++Output', 'FloatingPoint32Bit', 'FixedPoint16Bit', 'FloatingPoint16Bit', 'EnableDataPixxM16Output', 'EnableDataPixxC48Output'};
			app.OKbitDepth.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKbitDepth.Tag = 'OKbitDepth';
			app.OKbitDepth.Value = 'FloatingPoint32BitIfPossible';
			app.text191 = uilabel(app.GridLayout);
			app.text191.Tag = 'text191';
			app.text191.Tooltip = 'Bit Depth of backing framebuffer';
			app.text191.Text = 'Bit Depth';
			app.OKlogFrames = uicheckbox(app.GridLayout);
			app.OKlogFrames.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKlogFrames.Tag = 'OKlogFrames';
			app.OKlogFrames.Tooltip = 'Log each frame time to check for dropped frames etc.';
			app.OKlogFrames.Text = 'Log Times';
			app.OKlogFrames.Value = true;
			app.text189 = uilabel(app.GridLayout);
			app.text189.Tag = 'text189';
			app.text189.Text = 'Gamma';
			app.OKUseGamma = uidropdown(app.GridLayout);
			app.OKUseGamma.Items = {'None'};
			app.OKUseGamma.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKUseGamma.Tag = 'OKUseGamma';
			app.OKUseGamma.Value = 'None';
			app.OKrecordMovie = uicheckbox(app.GridLayout);
			app.OKrecordMovie.ValueChangedFcn = createCallbackFcn(app, @OKrecordMovie_Callback, true);
			app.OKrecordMovie.Tag = 'OKrecordMovie';
			app.OKrecordMovie.Tooltip = 'Enable PTB stimulus recording, currently buggy as gstreamer not stable';
			app.OKrecordMovie.Text = 'Record Movie';
			app.OKNativeBeamPosition = uicheckbox(app.GridLayout);
			app.OKNativeBeamPosition.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKNativeBeamPosition.Tag = 'OKNativeBeamPosition';
			app.OKNativeBeamPosition.Tooltip = 'Use the OS native beam position queries or override with PTB routine; override is better for OS X allong with use of the kernel driver';
			app.OKNativeBeamPosition.Text = 'Native BeamPos?';
			app.OKAntiAliasing = uieditfield(app.GridLayout, 'text');
			app.OKAntiAliasing.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKAntiAliasing.Tag = 'OKAntiAliasing';
			app.OKAntiAliasing.Tooltip = 'Global anti-aliasing';
			app.OKAntiAliasing.Value = '0';
			app.OKGLDst = uidropdown(app.GridLayout);
			app.OKGLDst.Items = {'GL_ZERO', 'GL_ONE', 'GL_DST_COLOR', 'GL_ONE_MINUS_DST_COLOR', 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA', 'GL_DST_ALPHA', 'GL_ONE_MINUS_DST_ALPHA', 'GL_SRC_ALPHA_SATURATE'};
			app.OKGLDst.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKGLDst.Tag = 'OKGLDst';
			app.OKGLDst.Value = 'GL_ONE_MINUS_SRC_ALPHA';
			app.OKGLSrc = uidropdown(app.GridLayout);
			app.OKGLSrc.Items = {'GL_ZERO', 'GL_ONE', 'GL_DST_COLOR', 'GL_ONE_MINUS_DST_COLOR', 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA', 'GL_DST_ALPHA', 'GL_ONE_MINUS_DST_ALPHA', 'GL_SRC_ALPHA_SATURATE'};
			app.OKGLSrc.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKGLSrc.Tag = 'OKGLSrc';
			app.OKGLSrc.Value = 'GL_SRC_ALPHA';
			app.OKDebug = uicheckbox(app.GridLayout);
			app.OKDebug.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKDebug.Tag = 'OKDebug';
			app.OKDebug.Tooltip = 'Show onscreen info and disregard timing errors';
			app.OKDebug.Text = 'Debug Mode';
			app.OKVerbose = uicheckbox(app.GridLayout);
			app.OKVerbose.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKVerbose.Tag = 'OKVerbose';
			app.OKVerbose.Tooltip = 'Show more information in the command window?';
			app.OKVerbose.Text = 'Verbose';
			app.OKUsePhotoDiode = uicheckbox(app.GridLayout);
			app.OKUsePhotoDiode.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKUsePhotoDiode.Tag = 'OKUsePhotoDiode';
			app.OKUsePhotoDiode.Enable = 'off';
			app.OKUsePhotoDiode.Visible = 'off';
			app.OKUsePhotoDiode.Tooltip = {'Show a white<->black square to log stimulus onset with a photodiode. For behavioural tasks you must include the correct drawing method to actually draw it.'};
			app.OKUsePhotoDiode.Text = 'Photodiode Present?';
			app.OKHideFlash = uicheckbox(app.GridLayout);
			app.OKHideFlash.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKHideFlash.Tag = 'OKHideFlash';
			app.OKHideFlash.Tooltip = 'Use Mario''s gamma trick to hide black flash onset';
			app.OKHideFlash.Text = 'Hide Flash?';
			app.text101 = uilabel(app.GridLayout);
			app.text101.Tag = 'text101';
			app.text101.Text = 'Dest blend';
			app.text100 = uilabel(app.GridLayout);
			app.text100.Tag = 'text100';
			app.text100.Text = 'Src Blend';
			app.OKSelectScreen = uidropdown(app.GridLayout);
			app.OKSelectScreen.Items = {'0', '1'};
			app.OKSelectScreen.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKSelectScreen.Tag = 'OKSelectScreen';
			app.OKSelectScreen.Value = '0';
			app.text83 = uilabel(app.GridLayout);
			app.text83.Tag = 'text83';
			app.text83.Text = 'Window Size';
			app.OKOpenGLBlending = uicheckbox(app.GridLayout);
			app.OKOpenGLBlending.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKOpenGLBlending.Tag = 'OKOpenGLBlending';
			app.OKOpenGLBlending.Tooltip = 'Enable global OpenGL blending mode in PTB?';
			app.OKOpenGLBlending.Text = 'OpenGL Blending';
			app.text7 = uilabel(app.GridLayout);
			app.text7.Tag = 'text7';
			app.text7.Text = 'Y Center';
			app.text6 = uilabel(app.GridLayout);
			app.text6.Tag = 'text6';
			app.text6.Text = 'X Center';
			app.OKscreenYOffset = uieditfield(app.GridLayout, 'text');
			app.OKscreenYOffset.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKscreenYOffset.Tag = 'OKscreenYOffset';
			app.OKscreenYOffset.Value = '0';
			app.OKscreenXOffset = uieditfield(app.GridLayout, 'text');
			app.OKscreenXOffset.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKscreenXOffset.Tag = 'OKscreenXOffset';
			app.OKscreenXOffset.Value = '0';
			app.OKWindowSize = uieditfield(app.GridLayout, 'text');
			app.OKWindowSize.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKWindowSize.Tag = 'OKWindowSize';
			app.OKWindowSize.Tooltip = 'Enter an [] matrix for automatic fullscreen or a [W H] matrix for a widthxheight window';
			app.OKWindowSize.Value = '[]';
			app.text5 = uilabel(app.GridLayout);
			app.text5.Tag = 'text5';
			app.text5.Text = 'Px / cm';
			app.text4 = uilabel(app.GridLayout);
			app.text4.Tag = 'text4';
			app.text4.Text = 'Distance (cm)';
			app.text3 = uilabel(app.GridLayout);
			app.text3.Tag = 'text3';
			app.text3.Text = 'Monitor';
			app.OKpixelsPerCm = uieditfield(app.GridLayout, 'text');
			app.OKpixelsPerCm.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKpixelsPerCm.Tag = 'OKpixelsPerCm';
			app.OKpixelsPerCm.Tooltip = 'Pixels per cm as calculated during screen calibration';
			app.OKpixelsPerCm.Value = '32';
			app.OKMonitorDistance = uieditfield(app.GridLayout, 'text');
			app.OKMonitorDistance.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKMonitorDistance.Tag = 'OKMonitorDistance';
			app.OKMonitorDistance.Value = '57.3';
			app.text102 = uilabel(app.GridLayout);
			app.text102.Tag = 'text102';
			app.text102.Text = 'Anti-Aliasing';
			app.OKUseRetina = uicheckbox(app.GridLayout);
			app.OKUseRetina.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKUseRetina.Tag = 'OKUseRetina';
			app.OKUseRetina.Tooltip = {'Use retina display mode?'};
			app.OKUseRetina.Text = 'Use Retina Mode?';
			app.OKUseDummy = uicheckbox(app.GridLayout);
			app.OKUseDummy.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKUseDummy.Tag = 'OKUseDummy';
			app.OKUseDummy.Tooltip = {'Put the eyetracker into dummy mode where mouse imitates eye position'};
			app.OKUseDummy.Text = 'Dummy Eyetracker?';
			app.OKUseFixation = uicheckbox(app.GridLayout);
			app.OKUseFixation.ValueChangedFcn = createCallbackFcn(app, @OKGetScreenVals, true);
			app.OKUseFixation.Tag = 'OKUseFixation';
			app.OKUseFixation.Enable = 'off';
			app.OKUseFixation.Visible = 'off';
			app.OKUseFixation.Tooltip = {'Draw a cross for the MOC task without needing to add a fixation cross stimulus'};
			app.OKUseFixation.Text = 'MOC Fixation?';
			app.TaskOptionstaskSequenceMethodofConstantsMOCTimingsPanel = uipanel(app.GridLayout);
			app.TaskOptionstaskSequenceMethodofConstantsMOCTimingsPanel.Title = 'Task Options <taskSequence> | Method of Constants (MOC) Timings';
			app.GridLayout2 = uigridlayout(app.TaskOptionstaskSequenceMethodofConstantsMOCTimingsPanel);
			app.OKrealTime = uicheckbox(app.GridLayout2);
			app.OKrealTime.ValueChangedFcn = createCallbackFcn(app, @OKrealTime_Callback, true);
			app.OKrealTime.Tag = 'OKrealTime';
			app.OKrealTime.Tooltip = 'If ON then we use absoloute time which is less liable to error build-up, otherwise measure time in number of frames needed to show, and even if frames are dropped, opticka simply shows the right number of frames ignoring possible time drift (better for record movie mode)';
			app.OKrealTime.Text = 'Use Clock Time?';
			app.OKrealTime.Value = true;
			app.text107 = uilabel(app.GridLayout2);
			app.text107.Tag = 'text107';
			app.text107.Text = 'MOC Trial Time';
			app.OKtrialTime = uieditfield(app.GridLayout2, 'text');
			app.OKtrialTime.ValueChangedFcn = createCallbackFcn(app, @OKtrialTime_Callback, true);
			app.OKtrialTime.Tag = 'OKtrialTime';
			app.OKtrialTime.Tooltip = {'Trial time for MOC run (NOT for behavioural tasks that use a state machine)'};
			app.OKtrialTime.Value = '2';
			app.text104 = uilabel(app.GridLayout2);
			app.text104.Tag = 'text104';
			app.text104.Text = 'MOC IB Time';
			app.OKibTime = uieditfield(app.GridLayout2, 'text');
			app.OKibTime.ValueChangedFcn = createCallbackFcn(app, @OKibTime_Callback, true);
			app.OKibTime.Tag = 'OKitTime';
			app.OKibTime.Tooltip = {'Inter-block time for MOC task run (not using state machine)'};
			app.OKibTime.Value = '1';
			app.text10 = uilabel(app.GridLayout2);
			app.text10.Tag = 'text10';
			app.text10.Text = 'MOC IT Time';
			app.text9 = uilabel(app.GridLayout2);
			app.text9.Tag = 'text9';
			app.text9.Tooltip = {'The number of blocked repetitions of the variables listed below...'};
			app.text9.Text = 'Repeat Blocks';
			app.OKisTime = uieditfield(app.GridLayout2, 'text');
			app.OKisTime.ValueChangedFcn = createCallbackFcn(app, @OKisTime_Callback, true);
			app.OKisTime.Tag = 'OKisTime';
			app.OKisTime.Tooltip = {'Inter-trial time for MOC run (not using state machine)'};
			app.OKisTime.Value = '0.5';
			app.OKnBlocks = uieditfield(app.GridLayout2, 'text');
			app.OKnBlocks.ValueChangedFcn = createCallbackFcn(app, @OKnBlocks_Callback, true);
			app.OKnBlocks.Tag = 'OKnBlocks';
			app.OKnBlocks.Tooltip = {'The number of blocked repetitions of the variables listed below...'};
			app.OKnBlocks.Value = '5';
			app.text106 = uilabel(app.GridLayout2);
			app.text106.Tag = 'text106';
			app.text106.Text = 'Random Seed';
			app.OKRandomSeed = uieditfield(app.GridLayout2, 'text');
			app.OKRandomSeed.ValueChangedFcn = createCallbackFcn(app, @OKRandomSeed_Callback, true);
			app.OKRandomSeed.Tag = 'OKRandomSeed';
			app.OKRandomSeed.Tooltip = 'Random seed for the random number generator';
			app.OKRandomSeed.Placeholder = '[ ]';
			app.OKRandomSeed.Value = '[ ]';
			app.text105 = uilabel(app.GridLayout2);
			app.text105.Tag = 'text105';
			app.text105.Text = 'Random Algorithm';
			app.OKrandomGenerator = uidropdown(app.GridLayout2);
			app.OKrandomGenerator.Items = {'mt19937ar', 'mcg16807', 'mrg32k3a', 'swb2712'};
			app.OKrandomGenerator.ValueChangedFcn = createCallbackFcn(app, @OKrandomGenerator_Callback, true);
			app.OKrandomGenerator.Tag = 'OKrandomGenerator';
			app.OKrandomGenerator.Value = 'mt19937ar';
			app.OKRandomise = uicheckbox(app.GridLayout2);
			app.OKRandomise.ValueChangedFcn = createCallbackFcn(app, @OKRandomise_Callback, true);
			app.OKRandomise.Tag = 'OKRandomise';
			app.OKRandomise.Text = 'Randomise Variable Order';
			app.OKRandomise.Value = true;
			app.BlockValuesEditFieldLabel = uilabel(app.GridLayout2);
			app.BlockValuesEditFieldLabel.Text = 'Block Values';
			app.OKBlockValues = uieditfield(app.GridLayout2, 'text');
			app.OKBlockValues.Tooltip = {'You can create a cell array of values that act as independent values which can be assigned to blocks based on a probability, for example values={''A'',''B''} proability=[0.7 0.3] will assign A and B to each block in a ratio of 70:30'};
			app.OKBlockValues.Value = '{''none''}';
			app.BlockProbabilityEditFieldLabel = uilabel(app.GridLayout2);
			app.BlockProbabilityEditFieldLabel.Text = 'Block Probability';
			app.OKBlockProbability = uieditfield(app.GridLayout2, 'text');
			app.OKBlockProbability.Tooltip = {'You can create a cell array of values that act as independent values which can be assigned to blocks based on a probability, for example values={''A'',''B''} proability=[0.7 0.3] will assign A and B to each block in a ratio of 70:30'};
			app.OKBlockProbability.Placeholder = '[ 1 ]';
			app.OKBlockProbability.Value = '[ 1 ]';
			app.TrialValuesEditFieldLabel = uilabel(app.GridLayout2);
			app.TrialValuesEditFieldLabel.Text = 'Trial Values';
			app.OKTrialValues = uieditfield(app.GridLayout2, 'text');
			app.OKTrialValues.Tooltip = {'You can create a cell array of values that act as independent values which can be assigned to trials based on a probability, for example values={''A'',''B''} proability=[0.7 0.3] will assign A and B to each block in a ratio of 70:30'};
			app.OKTrialValues.Value = '{''none''}';
			app.TrialProbabilityEditFieldLabel = uilabel(app.GridLayout2);
			app.TrialProbabilityEditFieldLabel.Text = 'Trial Probability';
			app.OKTrialProbability = uieditfield(app.GridLayout2, 'text');
			app.OKTrialProbability.Tooltip = {'You can create a cell array of values that act as independent values which can be assigned to trials based on a probability, for example values={''A'',''B''} proability=[0.7 0.3] will assign A and B to each block in a ratio of 70:30'};
			app.OKTrialProbability.Placeholder = '[ 1 ]';
			app.OKTrialProbability.Value = '[ 1 ]';
			app.text9_2 = uilabel(app.GridLayout2);
			app.text9_2.Tag = 'text9';
			app.text9_2.Tooltip = {'The number of blocked repetitions of the variables listed below...'};
			app.text9_2.Text = 'Start Index';
			app.OKStartIndex = uieditfield(app.GridLayout2, 'text');
			app.OKStartIndex.Tag = 'OKnBlocks';
			app.OKStartIndex.Tooltip = {''};
			app.OKStartIndex.Placeholder = '1';
			app.OKStartIndex.Value = '1';
			app.TaskTab = uitab(app.TabGroup);
			app.TaskTab.Title = 'Task';
			app.GridLayout3 = uigridlayout(app.TaskTab);
			app.OKTrainingName = uieditfield(app.GridLayout3, 'text');
			app.OKTrainingName.ValueChangedFcn = createCallbackFcn(app, @OKTrainingNameValueChanged, true);
			app.OKTrainingName.Tag = 'OKTrainingName';
			app.OKTrainingName.Value = 'Simulcra';
			app.text209 = uilabel(app.GridLayout3);
			app.text209.Tag = 'text209';
			app.text209.Text = 'Subject Name';
			app.OKLoadStateButton = uibutton(app.GridLayout3, 'push');
			app.OKLoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @OKLoadStateButton_Callback, true);
			app.OKLoadStateButton.Tag = 'OKLoadStateButton';
			app.OKLoadStateButton.Text = 'Load State File…';
			app.OKEditStateFileButon = uibutton(app.GridLayout3, 'push');
			app.OKEditStateFileButon.ButtonPushedFcn = createCallbackFcn(app, @OKEditStateFileButon_Callback, true);
			app.OKEditStateFileButon.Tag = 'OKEditStateFileButon';
			app.OKEditStateFileButon.Text = 'Edit State File…';
			app.OKTrainingResearcherName = uieditfield(app.GridLayout3, 'text');
			app.OKTrainingResearcherName.ValueChangedFcn = createCallbackFcn(app, @OKTrainingResearcherNameValueChanged, true);
			app.OKTrainingResearcherName.Tag = 'OKTrainingResearcherName';
			app.OKTrainingResearcherName.Value = 'Joanna Doe';
			app.text4554 = uilabel(app.GridLayout3);
			app.text4554.Tag = 'text4554';
			app.text4554.Text = 'Researcher';
			app.OKTrainingText = uitextarea(app.GridLayout3);
			app.OKTrainingText.Tag = 'OKTrainingText';
			app.OKTrainingText.Editable = 'off';
			app.OKTrainingText.Tooltip = {'To edit the file, click the "Edit state File" button'};
			app.OKTrainingText.Placeholder = 'Load a state info file...';
			app.OKRefreshStateInfo = uibutton(app.GridLayout3, 'push');
			app.OKRefreshStateInfo.ButtonPushedFcn = createCallbackFcn(app, @OKRefreshStateInfo_Callback, true);
			app.OKRefreshStateInfo.Tag = 'OKRefreshStateInfo';
			app.OKRefreshStateInfo.Text = 'Refresh View...';
			app.OKTrainingFileName = uilabel(app.GridLayout3);
			app.OKTrainingFileName.Tag = 'OKTrainingFileName';
			app.OKTrainingFileName.Text = 'File Name:';
			app.OthersTab = uitab(app.TabGroup);
			app.OthersTab.Title = 'Others';
			app.ProtocolsPanel = uipanel(app.OthersTab);
			app.ProtocolsPanel.Title = 'Protocols';
			app.OKProtocolLoad = uibutton(app.ProtocolsPanel, 'push');
			app.OKProtocolLoad.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolLoad_Callback, true);
			app.OKProtocolLoad.Tag = 'OKProtocolLoad';
			app.OKProtocolLoad.Text = 'Load...';
			app.OKProtocolSave = uibutton(app.ProtocolsPanel, 'push');
			app.OKProtocolSave.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolSaveOnly, true);
			app.OKProtocolSave.Tag = 'OKProtocolSave';
			app.OKProtocolSave.Text = 'Save As...';
			app.OKProtocolDelete = uibutton(app.ProtocolsPanel, 'push');
			app.OKProtocolDelete.ButtonPushedFcn = createCallbackFcn(app, @OKProtocolDelete_Callback, true);
			app.OKProtocolDelete.Tag = 'OKProtocolDelete';
			app.OKProtocolDelete.Enable = 'off';
			app.OKProtocolDelete.Text = 'Delete...';
			app.OKProtocolsList = uilistbox(app.ProtocolsPanel);
			app.OKProtocolsList.Items = {'AreaSummation', 'FigureGround', 'OrientationTuning', ''};
			app.OKProtocolsList.Tag = 'OKProtocolsList';
			app.OKProtocolsList.Value = 'AreaSummation';
			app.DefaultEyetrackersettingscanbeoverriddenbystatemachinefilePanel = uipanel(app.OthersTab);
			app.DefaultEyetrackersettingscanbeoverriddenbystatemachinefilePanel.Tooltip = {'History of loaded protocols'};
			app.DefaultEyetrackersettingscanbeoverriddenbystatemachinefilePanel.Title = 'Default Eyetracker settings (can be overridden by state machine file)';
			app.TabGroup2 = uitabgroup(app.DefaultEyetrackersettingscanbeoverriddenbystatemachinefilePanel);
			app.TobiiProTab = uitab(app.TabGroup2);
			app.TobiiProTab.Tooltip = {'Settings for Tobii Pro eyetrackers'};
			app.TobiiProTab.Title = 'Tobii Pro';
			app.CalibrationstimulusDropDownLabel = uilabel(app.TobiiProTab);
			app.CalibrationstimulusDropDownLabel.Text = 'Calibration stimulus';
			app.OKCalibrationstimulusDropDown = uidropdown(app.TobiiProTab);
			app.OKCalibrationstimulusDropDown.Items = {'animated', 'movie', 'normal'};
			app.OKCalibrationstimulusDropDown.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKCalibrationstimulusDropDown.Value = 'animated';
			app.TrackingmodeDropDownLabel = uilabel(app.TobiiProTab);
			app.TrackingmodeDropDownLabel.Text = 'Tracking mode';
			app.OKTrackingmodeDropDown = uidropdown(app.TobiiProTab);
			app.OKTrackingmodeDropDown.Items = {'human', 'macaque'};
			app.OKTrackingmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKTrackingmodeDropDown.Value = 'human';
			app.TrackerDropDownLabel = uilabel(app.TobiiProTab);
			app.TrackerDropDownLabel.Text = 'Tracker';
			app.OKTrackerDropDown = uidropdown(app.TobiiProTab);
			app.OKTrackerDropDown.Items = {'Tobii Pro Spectrum', 'Tobii TX300', 'Tobii 4C'};
			app.OKTrackerDropDown.ValueChangedFcn = createCallbackFcn(app, @OKTrackerDropDownValueChanged, true);
			app.OKTrackerDropDown.Value = 'Tobii Pro Spectrum';
			app.EyeusedDropDownLabel = uilabel(app.TobiiProTab);
			app.EyeusedDropDownLabel.Text = 'Eye used';
			app.OKEyeusedDropDown = uidropdown(app.TobiiProTab);
			app.OKEyeusedDropDown.Items = {'both', 'left', 'right'};
			app.OKEyeusedDropDown.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKEyeusedDropDown.Value = 'both';
			app.SamplerateKnobLabel = uilabel(app.TobiiProTab);
			app.SamplerateKnobLabel.Text = 'Sample rate';
			app.OKSamplerateKnob = uiknob(app.TobiiProTab, 'discrete');
			app.OKSamplerateKnob.Items = {'60', '90', '120', '150', '300', '600', '1200'};
			app.OKSamplerateKnob.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKSamplerateKnob.Value = '300';
			app.XEditField_2Label = uilabel(app.TobiiProTab);
			app.XEditField_2Label.Text = 'X';
			app.XOKEditField_Tobii = uieditfield(app.TobiiProTab, 'numeric');
			app.XOKEditField_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.XOKEditField_Tobii.Tooltip = {'X location for fixation window'};
			app.YEditField_2Label = uilabel(app.TobiiProTab);
			app.YEditField_2Label.Text = 'Y';
			app.OKYEditField_Tobii = uieditfield(app.TobiiProTab, 'numeric');
			app.OKYEditField_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKYEditField_Tobii.Tooltip = {'Y location for fixation window'};
			app.InitEditField_2Label = uilabel(app.TobiiProTab);
			app.InitEditField_2Label.Text = 'Init';
			app.OKInitEditField_Tobii = uieditfield(app.TobiiProTab, 'numeric');
			app.OKInitEditField_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKInitEditField_Tobii.Tooltip = {'Fixation initiation time (s)'};
			app.OKInitEditField_Tobii.Value = 1;
			app.DurationEditField_2Label = uilabel(app.TobiiProTab);
			app.DurationEditField_2Label.Text = 'Duration';
			app.OKDurationEditField_Tobii = uieditfield(app.TobiiProTab, 'numeric');
			app.OKDurationEditField_Tobii.Limits = [0.1 20];
			app.OKDurationEditField_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKDurationEditField_Tobii.Tooltip = {'Fixation maintain time (s)'};
			app.OKDurationEditField_Tobii.Value = 0.5;
			app.RadiusEditField_2Label = uilabel(app.TobiiProTab);
			app.RadiusEditField_2Label.Text = 'Radius';
			app.OKRadiusEditField_Tobii = uieditfield(app.TobiiProTab, 'numeric');
			app.OKRadiusEditField_Tobii.Limits = [0.1 20];
			app.OKRadiusEditField_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKRadiusEditField_Tobii.Tooltip = {'Fixation window radius'};
			app.OKRadiusEditField_Tobii.Value = 2;
			app.StrictEditField_2Label = uilabel(app.TobiiProTab);
			app.StrictEditField_2Label.Text = 'Strict';
			app.OKStrictEditField_Tobii = uieditfield(app.TobiiProTab, 'numeric');
			app.OKStrictEditField_Tobii.Limits = [0 1];
			app.OKStrictEditField_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKStrictEditField_Tobii.Tooltip = {'Strict fixation checking?'};
			app.OKStrictEditField_Tobii.Value = 1;
			app.DrawEyeDropDownLabel = uilabel(app.TobiiProTab);
			app.DrawEyeDropDownLabel.Text = 'Draw Eye?';
			app.OKDrawEyeDropDown = uidropdown(app.TobiiProTab);
			app.OKDrawEyeDropDown.Items = {'No', 'On screen', 'New Screen'};
			app.OKDrawEyeDropDown.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKDrawEyeDropDown.Value = 'No';
			app.ManualcalibrationSwitch_2Label = uilabel(app.TobiiProTab);
			app.ManualcalibrationSwitch_2Label.Text = 'Manual calibration';
			app.OKManualcalibrationSwitch_Tobii = uiswitch(app.TobiiProTab, 'rocker');
			app.OKManualcalibrationSwitch_Tobii.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKManualcalibrationSwitch_Tobii.Tooltip = {'Use manual (remote) calibration method?'};
			app.OKeyeCALButton = uibutton(app.TobiiProTab, 'push');
			app.OKeyeCALButton.ButtonPushedFcn = createCallbackFcn(app, @OKeyeCALButtonPushed, true);
			app.OKeyeCALButton.Icon = 'go-to-file.png';
			app.OKeyeCALButton.Text = '';
			app.OKPaceDurationEditField = uieditfield(app.TobiiProTab, 'numeric');
			app.OKPaceDurationEditField.Limits = [0.1 2];
			app.OKPaceDurationEditField.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKPaceDurationEditField.Tooltip = {'Pace duration (s)'};
			app.OKPaceDurationEditField.Value = 0.8;
			app.CalibrationfileLabel = uilabel(app.TobiiProTab);
			app.CalibrationfileLabel.Text = 'Calibration file';
			app.OKCalibrationfileEditField = uieditfield(app.TobiiProTab, 'text');
			app.OKCalibrationfileEditField.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKCalibrationfileEditField.Tooltip = {'Tobii trackers can export and import previous configurations. Opticka can save each config named by each subject and you can choose to load and save them at the start of calibration...'};
			app.ValEditFieldLabel = uilabel(app.TobiiProTab);
			app.ValEditFieldLabel.Text = 'Val';
			app.OKValEditField = uieditfield(app.TobiiProTab, 'text');
			app.OKValEditField.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKValEditField.Tooltip = {'Position of validation points'};
			app.OKValEditField.Value = '[ 0.5 0.5 ]';
			app.CalEditFieldLabel = uilabel(app.TobiiProTab);
			app.CalEditFieldLabel.Text = 'Cal';
			app.OKCalEditField = uieditfield(app.TobiiProTab, 'text');
			app.OKCalEditField.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKCalEditField.Tooltip = {'Positions of calibration points'};
			app.OKCalEditField.Value = '[ 0.2 0.5; 0.5 0.5; 0.8 0.5 ]';
			app.ReloadpreviousSwitchLabel = uilabel(app.TobiiProTab);
			app.ReloadpreviousSwitchLabel.Text = 'Reload previous';
			app.OKReloadpreviousSwitch = uiswitch(app.TobiiProTab, 'rocker');
			app.OKReloadpreviousSwitch.Tooltip = {'Automatically try to reload the previous calibration file?'};
			app.AutopaceSwitchLabel = uilabel(app.TobiiProTab);
			app.AutopaceSwitchLabel.Text = 'Auto pace';
			app.OKAutopaceSwitch = uiswitch(app.TobiiProTab, 'rocker');
			app.OKAutopaceSwitch.ValueChangedFcn = createCallbackFcn(app, @OKTobiiSettings, true);
			app.OKAutopaceSwitch.Tooltip = {'Use automatic pace to next calibration point?'};
			app.OKAutopaceSwitch.Value = 'On';
			app.EyelinkTab = uitab(app.TabGroup2);
			app.EyelinkTab.Title = 'Eyelink';
			app.IgnoreBlinksSwitchLabel = uilabel(app.EyelinkTab);
			app.IgnoreBlinksSwitchLabel.Text = 'Ignore Blinks?';
			app.OKIgnoreBlinksSwitch = uiswitch(app.EyelinkTab, 'rocker');
			app.OKIgnoreBlinksSwitch.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKIgnoreBlinksSwitch.Tooltip = {'Dummy mode for eyelink?'};
			app.XEditFieldLabel = uilabel(app.EyelinkTab);
			app.XEditFieldLabel.Text = 'X';
			app.OKXEditField = uieditfield(app.EyelinkTab, 'numeric');
			app.OKXEditField.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKXEditField.Tooltip = {'X location for fixation window'};
			app.YEditFieldLabel = uilabel(app.EyelinkTab);
			app.YEditFieldLabel.Text = 'Y';
			app.OKYEditField = uieditfield(app.EyelinkTab, 'numeric');
			app.OKYEditField.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKYEditField.Tooltip = {'Y location for fixation window'};
			app.InitEditFieldLabel = uilabel(app.EyelinkTab);
			app.InitEditFieldLabel.Text = 'Init';
			app.OKInitEditField = uieditfield(app.EyelinkTab, 'numeric');
			app.OKInitEditField.Limits = [0 500];
			app.OKInitEditField.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKInitEditField.Tooltip = {'Fixation initiation time (s)'};
			app.OKInitEditField.Value = 1;
			app.DurationEditFieldLabel = uilabel(app.EyelinkTab);
			app.DurationEditFieldLabel.Text = 'Duration';
			app.OKDurationEditField = uieditfield(app.EyelinkTab, 'numeric');
			app.OKDurationEditField.Limits = [0 20];
			app.OKDurationEditField.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKDurationEditField.Tooltip = {'Fixation maintain time (s)'};
			app.OKDurationEditField.Value = 0.5;
			app.RadiusEditFieldLabel = uilabel(app.EyelinkTab);
			app.RadiusEditFieldLabel.Text = 'Radius';
			app.OKRadiusEditField = uieditfield(app.EyelinkTab, 'numeric');
			app.OKRadiusEditField.Limits = [0.1 20];
			app.OKRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKRadiusEditField.Tooltip = {'Fixation window radius'};
			app.OKRadiusEditField.Value = 2;
			app.SampleRateKnobLabel = uilabel(app.EyelinkTab);
			app.SampleRateKnobLabel.Text = 'Sample Rate';
			app.OKSampleRateKnob = uiknob(app.EyelinkTab, 'discrete');
			app.OKSampleRateKnob.Items = {'250', '500', '1000', '2000'};
			app.OKSampleRateKnob.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKSampleRateKnob.Value = '500';
			app.CalibrationPointsKnobLabel = uilabel(app.EyelinkTab);
			app.CalibrationPointsKnobLabel.Text = 'Calibration Points';
			app.OKCalibrationPointsKnob = uiknob(app.EyelinkTab, 'discrete');
			app.OKCalibrationPointsKnob.Items = {'H3', 'HV3', 'HV5', 'HV9', 'HV13'};
			app.OKCalibrationPointsKnob.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibrationPointsKnob.Value = 'HV5';
			app.StrictEditFieldLabel = uilabel(app.EyelinkTab);
			app.StrictEditFieldLabel.Text = 'Strict';
			app.OKStrictEditField = uieditfield(app.EyelinkTab, 'numeric');
			app.OKStrictEditField.Limits = [0 1];
			app.OKStrictEditField.RoundFractionalValues = 'on';
			app.OKStrictEditField.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKStrictEditField.Tooltip = {'Strict fixation checking?'};
			app.OKStrictEditField.Value = 1;
			app.ManualcalibrationSwitchLabel = uilabel(app.EyelinkTab);
			app.ManualcalibrationSwitchLabel.Text = 'Manual calibration';
			app.OKManualcalibSwitch = uiswitch(app.EyelinkTab, 'rocker');
			app.OKManualcalibSwitch.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKManualcalibSwitch.Tooltip = {'Use manual (remote) calibration method?'};
			app.CalibrationPropEditFieldLabel = uilabel(app.EyelinkTab);
			app.CalibrationPropEditFieldLabel.Text = 'Calibration Prop';
			app.OKCalibProp = uieditfield(app.EyelinkTab, 'text');
			app.OKCalibProp.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibProp.Tooltip = {'The proportion of the screen to place calibration / validation points'};
			app.OKCalibProp.Value = '0.7  0.7';
			app.CalibrationColorLabel = uilabel(app.EyelinkTab);
			app.CalibrationColorLabel.Text = 'Calibration Color';
			app.OKCalibColor = uieditfield(app.EyelinkTab, 'text');
			app.OKCalibColor.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibColor.Tooltip = {'The color of the calibration point'};
			app.OKCalibColor.Value = '1 1 1';
			app.SizeLabel = uilabel(app.EyelinkTab);
			app.SizeLabel.Text = 'Size';
			app.OKCalibSize = uieditfield(app.EyelinkTab, 'numeric');
			app.OKCalibSize.Limits = [0.1 20];
			app.OKCalibSize.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibSize.Tooltip = {'Size of calibration point in percent of screen'};
			app.OKCalibSize.Value = 2;
			app.WidthLabel = uilabel(app.EyelinkTab);
			app.WidthLabel.Text = 'Width';
			app.OKCalibWidth = uieditfield(app.EyelinkTab, 'numeric');
			app.OKCalibWidth.Limits = [0 5];
			app.OKCalibWidth.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibWidth.Tooltip = {'Width of cross lines'};
			app.OKCalibWidth.Value = 0.15;
			app.WaitLabel = uilabel(app.EyelinkTab);
			app.WaitLabel.Text = 'Wait';
			app.OKCalibWait = uieditfield(app.EyelinkTab, 'numeric');
			app.OKCalibWait.Limits = [0 5000];
			app.OKCalibWait.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibWait.Tooltip = {'wait for mode ready time'};
			app.OKCalibWait.Value = 500;
			app.DeviceLabel = uilabel(app.EyelinkTab);
			app.DeviceLabel.Text = 'Device';
			app.OKCalibDevice = uieditfield(app.EyelinkTab, 'numeric');
			app.OKCalibDevice.Limits = [-10 500];
			app.OKCalibDevice.RoundFractionalValues = 'on';
			app.OKCalibDevice.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibDevice.Tooltip = {'Keyboard device index (-1 combines all keyboards together)'};
			app.OKCalibDevice.Value = -1;
			app.WaitLabel_2 = uilabel(app.EyelinkTab);
			app.WaitLabel_2.Text = 'Beep';
			app.OKCalibBeep = uieditfield(app.EyelinkTab, 'numeric');
			app.OKCalibBeep.Limits = [0 5000];
			app.OKCalibBeep.ValueChangedFcn = createCallbackFcn(app, @OKEyelinkSettings, true);
			app.OKCalibBeep.Tooltip = {'Auditory beeps during calibration?'};
			app.OKCalibBeep.Value = 1;
			app.CalibrationSettingsLabel = uilabel(app.EyelinkTab);
			app.CalibrationSettingsLabel.Text = 'Calibration Settings';
			app.OtherSettingsPanel = uipanel(app.OthersTab);
			app.OtherSettingsPanel.Title = 'Other Settings';
			app.OKOmniplexIP = uieditfield(app.OtherSettingsPanel, 'text');
			app.OKOmniplexIP.Tag = 'OKOmniplexIP';
			app.OKOmniplexIP.Enable = 'off';
			app.OKOmniplexIP.Value = '144.82.131.19';
			app.OKOmniplexPort = uieditfield(app.OtherSettingsPanel, 'text');
			app.OKOmniplexPort.Tag = 'OKOmniplexPort';
			app.OKOmniplexPort.Enable = 'off';
			app.OKOmniplexPort.Value = '9889';
			app.text185 = uilabel(app.OtherSettingsPanel);
			app.text185.Tag = 'text185';
			app.text185.Enable = 'off';
			app.text185.Text = 'Port';
			app.OKOmniplexEnable = uicheckbox(app.OtherSettingsPanel);
			app.OKOmniplexEnable.ValueChangedFcn = createCallbackFcn(app, @OKOmniplexEnable_Callback, true);
			app.OKOmniplexEnable.Tag = 'OKOmniplexEnable';
			app.OKOmniplexEnable.Enable = 'off';
			app.OKOmniplexEnable.Text = 'Omniplex?';
			app.OKeyeOccluder = uieditfield(app.OtherSettingsPanel, 'text');
			app.OKeyeOccluder.Tag = 'OKeyeOccluder';
			app.OKeyeOccluder.Enable = 'off';
			app.OKeyeOccluder.Value = '/dev/tty.usbmodem5d11';
			app.text208 = uilabel(app.OtherSettingsPanel);
			app.text208.Tag = 'text208';
			app.text208.Enable = 'off';
			app.text208.Text = 'IP';
			app.OKarduinoPort = uieditfield(app.OtherSettingsPanel, 'text');
			app.OKarduinoPort.Tag = 'OKeyeOccluder';
			app.OKarduinoPort.Tooltip = {'Port for arduino, if empty, guess the port to use...'};
			app.OKarduinoPort.Value = '/dev/ttyUSB0';
			app.OKEyeoccluderLabel = uilabel(app.OtherSettingsPanel);
			app.OKEyeoccluderLabel.Enable = 'off';
			app.OKEyeoccluderLabel.Text = 'Eye-occluder ';
			app.OKArduinoportLabel = uilabel(app.OtherSettingsPanel);
			app.OKArduinoportLabel.Text = 'Arduino port';
			app.OKdPPMode = uieditfield(app.OtherSettingsPanel, 'text');
			app.OKdPPMode.ValueChangedFcn = createCallbackFcn(app, @OKdPPModeValueChanged, true);
			app.OKdPPMode.Tag = 'OKeyeOccluder';
			app.OKdPPMode.Tooltip = {'Strobe mode -- plain for normal strobe, and plexon for a plexon style strobe+trigger bit'};
			app.OKdPPMode.Value = 'plain';
			app.OKStrobemodeLabel = uilabel(app.OtherSettingsPanel);
			app.OKStrobemodeLabel.Text = 'Strobe mode';
			app.Image = uiimage(app.OKRoot);
			app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
			app.Image.ImageSource = 'opticka-clear.png';
			% Show the figure after all components are created
			app.OKRoot.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = opticka_ui(varargin)

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.OKRoot)

				% Execute the startup function
				runStartupFcn(app, @(app)opticka_Start(app, varargin{:}))
			else

				% Focus the running singleton app
				figure(runningApp.OKRoot)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.OKRoot)
		end
	end
end